classdef TheGreatUniter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GreatUniterUIFigure     matlab.ui.Figure
        Slider                  matlab.ui.control.Slider
        InputWindowLabel        matlab.ui.control.Label
        SendButton              matlab.ui.control.Button
        InputTextArea           matlab.ui.control.TextArea
        testButton              matlab.ui.control.StateButton
        StatusPanel             matlab.ui.container.Panel
        PlotinnewwindowButton   matlab.ui.control.Button
        YDropDown               matlab.ui.control.DropDown
        YDropDownLabel          matlab.ui.control.Label
        XDropDown               matlab.ui.control.DropDown
        XDropDownLabel          matlab.ui.control.Label
        StatusUITable           matlab.ui.control.Table
        UIAxesProgress2         matlab.ui.control.UIAxes
        UIAxesProgress          matlab.ui.control.UIAxes
        StatusUIAxes            matlab.ui.control.UIAxes
        SelectDevicePanel       matlab.ui.container.Panel
        addButton               matlab.ui.control.Button
        LabelDevice             matlab.ui.control.Label
        DevicesTree             matlab.ui.container.Tree
        OPMRubinNode            matlab.ui.container.TreeNode
        OSAYokogawaNode         matlab.ui.container.TreeNode
        FPGANode                matlab.ui.container.TreeNode
        ATTENUATORnimyDAQNode   matlab.ui.container.TreeNode
        SWITCHOSAEDFANode       matlab.ui.container.TreeNode
        TheGreatUniterLabel     matlab.ui.control.Label
        OutputWindowPanel       matlab.ui.container.Panel
        savelogButton           matlab.ui.control.Button
        PercentLabel            matlab.ui.control.Label
        ProgressLabel           matlab.ui.control.Label
        WARNINGLaserinuseLabel  matlab.ui.control.Label
        Lamp                    matlab.ui.control.Lamp
        clearButton             matlab.ui.control.Button
        OutputWindowLabel       matlab.ui.control.Label
        OutputTextArea          matlab.ui.control.TextArea
        SelectActionPanel       matlab.ui.container.Panel
        LabelTest               matlab.ui.control.Label
        RunButton               matlab.ui.control.Button
        Tree                    matlab.ui.container.Tree
        TestFPGAcomponentsNode  matlab.ui.container.TreeNode
        PhotodiodeNode          matlab.ui.container.TreeNode
        AttenuatorNode          matlab.ui.container.TreeNode
        TransceiverNode         matlab.ui.container.TreeNode
        ParameterNode           matlab.ui.container.TreeNode
        LoaddriverNode          matlab.ui.container.TreeNode
        LaserdiodeNode          matlab.ui.container.TreeNode
        TestEDFANode            matlab.ui.container.TreeNode
        CalibrateEDFANode       matlab.ui.container.TreeNode
        PanelFigures            matlab.ui.container.Panel
        SelectFolderPanel       matlab.ui.container.Panel
        SelectfolderforsavingfilesTextArea  matlab.ui.control.TextArea
        Label                   matlab.ui.control.Label
        SelectFolderButton      matlab.ui.control.Button
    end

    properties
        folder
        %userText
        x%
        y%
    end
    properties
        errorColor = [1.00,0.77,0.77];
        customColor = [0.96,0.96,1.00];
        defaultColor = [1.00,1.00,1.00];
    end
    properties %devices
        AddDevice_OPMrubin
        structDevice_OPMrubin
        AddDevice_OSAyokogawa
        AddDevice_FPGA
        AddDevice_SWITCHosaEdfa
        AddDevice_ATTENniMyDaq
    end
    properties %functions
        TestFPGA_Attenuator
        calledAppTestTransceiver
        calledAppTestPhotodiode
        calledAppTestLaserDiode
        calledAppTestLoadDriver
        calledAppTestOtherParameter
        calledAppAutoTestEDFA
        calledAppAutoCalibEDFA
        calledTestVD_minMeanMax
        calledAppTestCoeff
    end
    methods (Access = public)
        function appendStructDevice_OPMrubin(app,appDevice,deviceName)
            app.structDevice_OPMrubin.(deviceName) = appDevice;
%             testApp = app.structDevice_OPMrubin;
%             assignin("base","testApp",testApp);
        end

        
        function removeStructDevice_OPMrubin(app,deviceName)
            if isfield(app.structDevice_OPMrubin,deviceName)
                app.structDevice_OPMrubin = rmfield(app.structDevice_OPMrubin,deviceName);
            end
%             testApp = app.structDevice_OPMrubin;
%             assignin("base","testApp",testApp);
        end
    end
    methods (Access = public)
        % Очищение окна вывода
        function clearOutput(app)
            app.OutputTextArea.Value = '';
        end
        % Добавить строку в окно вывода
        function addStrOutput(app,str)
            oldOutput = app.OutputTextArea.Value;
            newOutput = strcat(datestr(now, 'HH:MM:SS'),': ',str);
            app.OutputTextArea.Value = [oldOutput; newOutput];
        end
        function clearMainTable(app)
            app.StatusUITable.Data = [];
        end
        function appendMainTable(app,newRow)
            app.StatusUITable.Data = [app.StatusUITable.Data; newRow];
        end
        function setLabelsProgressAxes(app,title,xLabel,yLabel)
            %title(app.UIAxesProgress,title);
            xlabel(app.UIAxesProgress,xLabel);
            ylabel(app.UIAxesProgress,yLabel);

        end
        function addPointProgressAxes(app,x,y,color)
            hold(app.UIAxesProgress,'on')
            plot(app.UIAxesProgress,x,y,'.','MarkerSize',10,'Color',color);
        end
        function clearProgressAxes(app)
            cla(app.UIAxesProgress);
        end
        function setLabelsProgressAxes2(app,title,xLabel,yLabel)
            %title(app.UIAxesProgress2,title);
            xlabel(app.UIAxesProgress2,xLabel);
            ylabel(app.UIAxesProgress2,yLabel);
        end
        function addPointProgressAxes2(app,x,y,color)
            hold(app.UIAxesProgress2,'on')
            plot(app.UIAxesProgress2,x,y,'.','MarkerSize',10,'Color',color);
        end
        function clearProgressAxes2(app)
            cla(app.UIAxesProgress2);
        end


        %         function setLegendProgressAxes(app,legend)
        %             legend(app.UIAxesProgress,legend);
        %         end

        function enableWarningLaser(app)
            app.WARNINGLaserinuseLabel.FontColor = [1, 1, 1];
            app.WARNINGLaserinuseLabel.BackgroundColor = [1, 0, 0];
            app.Lamp.Color = [1, 0, 0];
        end
        function disableWarningLaser(app)
            app.WARNINGLaserinuseLabel.FontColor = [0.80, 0.80, 0.80];
            app.WARNINGLaserinuseLabel.BackgroundColor = 'none';
            app.Lamp.Color = [0.94,0.94,0.94];
        end
        function refreshProgress(app,newProgress)
            app.ProgressLabel.Text = num2str(newProgress);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            app.structDevice_OPMrubin = struct;

            %app.folder = app.SelectfolderforsavingfilesTextArea.Value{1};
            %добавить из сохраненных переменных
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            dir = uigetdir('C:\EDFA\AllTestsEDFA','Select folder');
            if isequal(dir,0)
                output = '';
                app.SelectDevicePanel.Enable = 'off';
            else
                output = [dir,filesep];
                app.SelectDevicePanel.Enable = 'on';
            end
            app.folder = output;
            app.SelectfolderforsavingfilesTextArea.Value = output;
            %figure(app.GreatUniterUIFigure);
        end

        % Button pushed function: addButton
        function addButtonPushed(app, event)
            value = app.DevicesTree.SelectedNodes;

            if value == app.OPMRubinNode
                app.AddDevice_OPMrubin = appAddDevice_OPMrubin(app);

            elseif value == app.OSAYokogawaNode
                app.AddDevice_OSAyokogawa = appAddDevice_OSAyokogawa(app);

            elseif value == app.FPGANode
                app.AddDevice_FPGA = appAddDevice_FPGA(app);

            elseif value == app.ATTENUATORnimyDAQNode
                app.AddDevice_ATTENniMyDaq = appAddDevice_ATTENniMyDaq(app);

            elseif value == app.SWITCHOSAEDFANode
                app.AddDevice_SWITCHosaEdfa = appAddDevice_SWITCHosaEdfa(app);

            end
            
            app.SelectActionPanel.Enable = 'on';
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %             if isequal(app.SelectfolderforsavingfilesTextArea.Value{1},'не выбрана')
            %                 error('Выберите папку и перезапустите проверку')
            %             end
            value = app.Tree.SelectedNodes;
            if value == app.AttenuatorNode
                app.TestFPGA_Attenuator = appTestFPGA_Attenuator(app);
            elseif value == app.TransceiverNode
                app.calledAppTestTransceiver = appTestTransceiver(app);
            elseif value == app.PhotodiodeNode
                app.calledAppTestPhotodiode = appTestPhotodiode(app);
            elseif value == app.LaserdiodeNode
                app.calledAppTestLaserDiode = appTestLaserDiode(app);
            elseif value == app.LoaddriverNode
                app.calledAppTestLoadDriver = appTestLoadDriver(app);
            elseif value == app.ParameterNode
                app.calledAppTestOtherParameter = appTestOtherParameter(app);

            elseif value == app.AutotestEDFANode
                app.calledAppAutoTestEDFA = appAutoTestEDFA(app);
            elseif value == app.CalibrateEDFANode
                app.calledAppAutoCalibEDFA = appAutoCalibEDFA(app);

            elseif value == app.TestVDminmeanmaxNode
                app.calledTestVD_minMeanMax = TestVD_minMeanMax(app);
                
            elseif value == app.TestcoeffNode
app.calledAppTestCoeff = appTestCoeff(app);
            end
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            clc;
            clearOutput(app);
            clearMainTable(app);
            clearProgressAxes(app);
            clearProgressAxes2(app);
            cla(app.StatusUIAxes);
        end

        % Value changed function: testButton
        function testButtonValueChanged(app, event)
%             value = app.testButton.Value;
%             if value
%                 enableWarningLaser(app);
%                 newProgress = 50;
%                 refreshProgress(app,newProgress);
% str = 'test1';
%                 addStrOutput(app,str);
%             else
%                 disableWarningLaser(app);
%                 newProgress = 100;
%                 refreshProgress(app,newProgress);
% str = 'test2';
% addStrOutput(app,str);
%             end

        end

        % Button pushed function: savelogButton
        function savelogButtonPushed(app, event)
            textForSave = app.OutputTextArea.Value
            save('textForSave.mat','textForSave')
%             strForSave = [app.folder createFilename('log',now) '.txt'];
%             fid=fopen(strForSave,'w');
%             fprintf(fid, textForSave);
%             fclose(fid);
% 
%             for k=1:length(fileName)
%     List=textread(fileName{1,k},'%s','delimiter','\n');
%     %arrange them in order of k if you want in a cell array
%     FinalList{k,1}=List;
%     %or print them into a file.
%     fprintf(fid, [cell2mat(List)  '\n']);
% end
        end

        % Close request function: GreatUniterUIFigure
        function GreatUniterUIFigureCloseRequest(app, event)
            close all
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GreatUniterUIFigure = uifigure('Visible', 'off');
            app.GreatUniterUIFigure.Name = 'The Great Uniter';
            app.GreatUniterUIFigure.Icon = 'gu_mainicon.png';
            app.GreatUniterUIFigure.CloseRequestFcn = createCallbackFcn(app, @GreatUniterUIFigureCloseRequest, true);
            app.SelectFolderPanel = uipanel(app.GreatUniterUIFigure);
            app.SelectFolderButton = uibutton(app.SelectFolderPanel, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Icon = 'gu_folder.png';
            app.SelectFolderButton.Tooltip = {'select folder'};
            app.SelectFolderButton.Text = '';
            app.Label = uilabel(app.SelectFolderPanel);
            app.Label.Text = 'Select folder for saving files';
            app.SelectfolderforsavingfilesTextArea = uitextarea(app.SelectFolderPanel);
            app.SelectfolderforsavingfilesTextArea.Editable = 'off';
            app.PanelFigures = uipanel(app.GreatUniterUIFigure);
            app.SelectActionPanel = uipanel(app.GreatUniterUIFigure);
            app.SelectActionPanel.Enable = 'off';
            app.Tree = uitree(app.SelectActionPanel);
            app.TestFPGAcomponentsNode = uitreenode(app.Tree);
            app.TestFPGAcomponentsNode.Text = 'Test FPGA components';
            app.PhotodiodeNode = uitreenode(app.TestFPGAcomponentsNode);
            app.PhotodiodeNode.Text = 'Photodiode';
            app.AttenuatorNode = uitreenode(app.TestFPGAcomponentsNode);
            app.AttenuatorNode.Text = 'Attenuator';
            app.TransceiverNode = uitreenode(app.TestFPGAcomponentsNode);
            app.TransceiverNode.Text = 'Transceiver';
            app.ParameterNode = uitreenode(app.TestFPGAcomponentsNode);
            app.ParameterNode.Text = 'Parameter';
            app.LoaddriverNode = uitreenode(app.TestFPGAcomponentsNode);
            app.LoaddriverNode.Text = 'Load driver';
            app.LaserdiodeNode = uitreenode(app.TestFPGAcomponentsNode);
            app.LaserdiodeNode.Text = 'Laser diode';
            app.TestEDFANode = uitreenode(app.Tree);
            app.TestEDFANode.Text = 'Test EDFA';
            app.CalibrateEDFANode = uitreenode(app.Tree);
            app.CalibrateEDFANode.Text = 'Calibrate EDFA';
            app.RunButton = uibutton(app.SelectActionPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Icon = 'gu_plus-math.png';
            app.RunButton.Tooltip = {'select action'};
            app.RunButton.Text = '';
            app.LabelTest = uilabel(app.SelectActionPanel);
            app.LabelTest.Text = 'Select action and press button';
            app.OutputWindowPanel = uipanel(app.GreatUniterUIFigure);
            app.OutputTextArea = uitextarea(app.OutputWindowPanel);
            app.OutputTextArea.Editable = 'off';
            app.OutputWindowLabel = uilabel(app.OutputWindowPanel);
            app.OutputWindowLabel.Text = 'Output Window';
            app.clearButton = uibutton(app.OutputWindowPanel, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            app.Lamp = uilamp(app.OutputWindowPanel);
            app.WARNINGLaserinuseLabel = uilabel(app.OutputWindowPanel);
            app.WARNINGLaserinuseLabel.Text = {'WARNING'; 'Laser in use'};
            app.ProgressLabel = uilabel(app.OutputWindowPanel);
            app.ProgressLabel.Text = '0';
            app.PercentLabel = uilabel(app.OutputWindowPanel);
            app.PercentLabel.Text = '%';
            app.savelogButton = uibutton(app.OutputWindowPanel, 'push');
            app.savelogButton.ButtonPushedFcn = createCallbackFcn(app, @savelogButtonPushed, true);
            app.savelogButton.Text = 'save log';
            app.TheGreatUniterLabel = uilabel(app.GreatUniterUIFigure);
            app.TheGreatUniterLabel.Text = 'The Great Uniter';
            app.SelectDevicePanel = uipanel(app.GreatUniterUIFigure);
            app.SelectDevicePanel.Enable = 'off';
            app.DevicesTree = uitree(app.SelectDevicePanel);
            app.OPMRubinNode = uitreenode(app.DevicesTree);
            app.OPMRubinNode.Text = 'OPM | Rubin';
            app.OSAYokogawaNode = uitreenode(app.DevicesTree);
            app.OSAYokogawaNode.Text = 'OSA | Yokogawa';
            app.FPGANode = uitreenode(app.DevicesTree);
            app.FPGANode.Text = 'FPGA';
            app.ATTENUATORnimyDAQNode = uitreenode(app.DevicesTree);
            app.ATTENUATORnimyDAQNode.Text = 'ATTENUATOR | ni myDAQ';
            app.SWITCHOSAEDFANode = uitreenode(app.DevicesTree);
            app.SWITCHOSAEDFANode.Text = 'SWITCH | OSA/EDFA';
            app.LabelDevice = uilabel(app.SelectDevicePanel);
            app.LabelDevice.Text = 'Select device and press button';
            app.addButton = uibutton(app.SelectDevicePanel, 'push');
            app.addButton.ButtonPushedFcn = createCallbackFcn(app, @addButtonPushed, true);
            app.addButton.Icon = 'gu_plus-math.png';
            app.addButton.Tooltip = {'select device'};
            app.addButton.Text = '';
            app.StatusPanel = uipanel(app.GreatUniterUIFigure);
            app.StatusUIAxes = uiaxes(app.StatusPanel);
            title(app.StatusUIAxes, 'Title')
            xlabel(app.StatusUIAxes, 'X')
            ylabel(app.StatusUIAxes, 'Y')
            zlabel(app.StatusUIAxes, 'Z')
            app.UIAxesProgress = uiaxes(app.StatusPanel);
            title(app.UIAxesProgress, 'Title')
            xlabel(app.UIAxesProgress, 'X')
            ylabel(app.UIAxesProgress, 'Y')
            zlabel(app.UIAxesProgress, 'Z')
            app.UIAxesProgress2 = uiaxes(app.StatusPanel);
            title(app.UIAxesProgress2, 'Title')
            xlabel(app.UIAxesProgress2, 'X')
            ylabel(app.UIAxesProgress2, 'Y')
            zlabel(app.UIAxesProgress2, 'Z')
            app.StatusUITable = uitable(app.StatusPanel);
            app.StatusUITable.ColumnName = {'Name'; 'Value'};
            app.StatusUITable.RowName = {};
            app.StatusUITable.ColumnEditable = true;
            app.XDropDownLabel = uilabel(app.StatusPanel);
            app.XDropDownLabel.Enable = 'off';
            app.XDropDownLabel.Text = 'X ';
            app.XDropDown = uidropdown(app.StatusPanel);
            app.XDropDown.Enable = 'off';
            app.YDropDownLabel = uilabel(app.StatusPanel);
            app.YDropDownLabel.Enable = 'off';
            app.YDropDownLabel.Text = 'Y';
            app.YDropDown = uidropdown(app.StatusPanel);
            app.YDropDown.Enable = 'off';
            app.PlotinnewwindowButton = uibutton(app.StatusPanel, 'push');
            app.PlotinnewwindowButton.Enable = 'off';
            app.PlotinnewwindowButton.Text = 'Plot in new window';
            app.testButton = uibutton(app.GreatUniterUIFigure, 'state');
            app.testButton.ValueChangedFcn = createCallbackFcn(app, @testButtonValueChanged, true);
            app.testButton.Text = 'test';
            app.InputTextArea = uitextarea(app.GreatUniterUIFigure);
            app.InputTextArea.Enable = 'off';
            app.SendButton = uibutton(app.GreatUniterUIFigure, 'push');
            app.SendButton.Enable = 'off';
            app.SendButton.Text = 'Send';
            app.InputWindowLabel = uilabel(app.GreatUniterUIFigure);
            app.InputWindowLabel.Enable = 'off';
            app.InputWindowLabel.Text = 'Input Window';
            app.Slider = uislider(app.GreatUniterUIFigure);
            app.Slider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.Slider.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.Slider.MinorTicks = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40 42 44 46 48 50 52 54 56 58 60 62 64 66 68 70 72 74 76 78 80 82 84 86 88 90 92 94 96 98 100];
            % Show the figure after all components are created
            app.GreatUniterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TheGreatUniter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GreatUniterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GreatUniterUIFigure)
        end
    end
end