classdef appAddDevice_ATTENniMyDaq < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ConnectPanel                  matlab.ui.container.Panel
        Tip1Image                     matlab.ui.control.Image
        plotButton                    matlab.ui.control.Button
        AddTableButton                matlab.ui.control.Button
        MainTablePathTextArea         matlab.ui.control.TextArea
        AddattenuatortableVoltagevsAttenuationLabel  matlab.ui.control.Label
        ConnectSwitch                 matlab.ui.control.Switch
        FunctionPanel                 matlab.ui.container.Panel
        rangeLabel                    matlab.ui.control.Label
        Tip2Image                     matlab.ui.control.Image
        dBmLabel                      matlab.ui.control.Label
        okButton                      matlab.ui.control.Button
        Attenuationao0EditField       matlab.ui.control.NumericEditField
        Attenuationao0EditFieldLabel  matlab.ui.control.Label
        AttenuatornimyDAQLabel        matlab.ui.control.Label
    end

    properties (Access = public)
        callingApp
        folder
    end
    properties (Access = public)
        mainTable
        funcAttenVolt
        mainObj
    end
    methods (Access = public)
        function output = connect(app)
             try
                app.mainObj = [];
                app.mainObj = Device_ATTENniMyDaq(app);
                output = 'Attenuator connected';
                app.FunctionPanel.Enable = 'on';
             catch ME
                report = getReport(ME);
                uialert(app.callingApp.GreatUniterUIFigure,report,'Error Message','Interpreter','html');
                output = "Wrong connection";
                app.ConnectSwitch.Value = "Disconnect";
             end
        end
        function output = disconnect(app)
            if ~isequal(class(app.attenuatorDicon),'double')
                app.mainObj = deleteVirtualObject(app.mainObj);
                output = 'Attenuator disconnected';
            end
            app.FunctionPanel.Enable = 'off';
        end
        function readAttenuatorTable(app)
            app.mainTable = readmatrix(app.MainTablePathTextArea);
            [Attenuation_dBm, Voltage_V] = prepareCurveData(app.mainTable(:,"Attenuation_dBm"), app.mainTable(:,"Voltage_V"));
            [app.funcAttenVolt, gof] = fit(Attenuation_dBm, Voltage_V, 'linearinterp', 'Normalize', 'on' );
        end
        function editRangeString(app)
            minAttenuationStr = num2str(min(app.mainTable(:,"Attenuation_dBm")));
            maxAttenuationStr = num2str(max(app.mainTable(:,"Attenuation_dBm")));
            app.rangeLabel.Text = strcat("Attenuation must be in range from ",...
                minAttenuationStr," to ",maxAttenuationStr);
        end
        function defaultRangeString(app)
            app.rangeLabel.Text = "Attenuation must be in range from ____ to ____";
        end
        function loadFromMemFileToApp(app)
            %load("TheGreatUniterMemory.mat","appAddDevice_ATTENniMyDaq");
%app.MainTablePathTextArea.Value =
%appAddDevice_ATTENniMyDaq.mainTablePath
        end
        function saveFromAppToMemFile(app)
%appAddDevice_ATTENniMyDaq = struct()
            %save("TheGreatUniterMemory.mat","appAddDevice_ATTENniMyDaq");
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.callingApp = MainApp;
            app.folder = MainApp.folder;
            
loadFromMemFileToApp(app)

            %app.tableAttenuatorDiconPassport = app.MainTablePathTextArea.Value{1};
readAttenuatorTable(app)
        end

        % Button pushed function: AddTableButton
        function AddTableButtonPushed(app, event)
            [file,path] = uigetfile('*.xlsx','Select attenuator table','AttenuatorDiconPassportCut.xlsx');
            if isequal(file,0)
                output = '';
            else
                output = [path file];
            end
            %figure(app.UIFigure);
            %app.tableAttenuatorDiconPassport = output;
            app.MainTablePathTextArea.Value = output;

            obj.mainTable
%open table
% Set up fittype and options.
                    
                    % fitresult - функция, на вход принимает аттенюацию, на выходе
                    % - напряжение


            folder = uigetdir('C:\EDFA\AllTestsEDFA','Select folder');
            if isequal(folder,0)
                output = '';
            else
                output = [folder,filesep];
                app.folder = output;
                app.SelectfolderforsavingfilesTextArea.Value = output;
                app.ConnectPanel.Enable = 'on';
            end
        end

        % Value changed function: ConnectSwitch
        function ConnectSwitchValueChanged(app, event)
            value = app.ConnectSwitch.Value;
            if value == "Connect"
                output = connect(app);
            elseif value == "Disconnect"
                output = disconnect(app);
            end
            addStrOutput(app.callingApp,output);
        end

        % Button pushed function: okButton
        function okButtonPushed(app, event)
            attenuation = app.Attenuationao0EditField.Value;
            app.mainObj = setAttenuation(app.mainObj,attenuation);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AttenuatornimyDAQLabel = uilabel(app.UIFigure);
            app.AttenuatornimyDAQLabel.Text = 'Attenuator (ni myDAQ)';
            app.FunctionPanel = uipanel(app.UIFigure);
            app.FunctionPanel.Enable = 'off';
            app.Attenuationao0EditFieldLabel = uilabel(app.FunctionPanel);
            app.Attenuationao0EditFieldLabel.Text = 'Attenuation (ao0)';
            app.Attenuationao0EditField = uieditfield(app.FunctionPanel, 'numeric');
            app.okButton = uibutton(app.FunctionPanel, 'push');
            app.okButton.ButtonPushedFcn = createCallbackFcn(app, @okButtonPushed, true);
            app.okButton.Text = 'ok';
            app.dBmLabel = uilabel(app.FunctionPanel);
            app.dBmLabel.Text = 'dBm';
            app.Tip2Image = uiimage(app.FunctionPanel);
            app.Tip2Image.Tooltip = {'Attenuation should be in range [minimum;maximum] from table.'; 'Here we use Analog Output channel 0.'};
            app.Tip2Image.ImageSource = 'gu_help.png';
            app.rangeLabel = uilabel(app.FunctionPanel);
            app.rangeLabel.Text = '(must be in range ____ - ____)';
            app.ConnectPanel = uipanel(app.UIFigure);
            app.ConnectSwitch = uiswitch(app.ConnectPanel, 'slider');
            app.ConnectSwitch.Items = {'Disconnect', 'Connect'};
            app.ConnectSwitch.ValueChangedFcn = createCallbackFcn(app, @ConnectSwitchValueChanged, true);
            app.ConnectSwitch.Value = 'Disconnect';
            app.AddattenuatortableVoltagevsAttenuationLabel = uilabel(app.ConnectPanel);
            app.AddattenuatortableVoltagevsAttenuationLabel.Text = {'Add attenuator table'; '(Voltage vs. Attenuation)'};
            app.MainTablePathTextArea = uitextarea(app.ConnectPanel);
            app.MainTablePathTextArea.Editable = 'off';
            app.MainTablePathTextArea.Value = {'AttenuatorDiconPassportCut.xlsx'};
            app.AddTableButton = uibutton(app.ConnectPanel, 'push');
            app.AddTableButton.ButtonPushedFcn = createCallbackFcn(app, @AddTableButtonPushed, true);
            app.AddTableButton.Icon = 'gu_microsoft-excel.png';
            app.AddTableButton.Text = '';
            app.plotButton = uibutton(app.ConnectPanel, 'push');
            app.plotButton.Text = 'plot';
            app.Tip1Image = uiimage(app.ConnectPanel);
            app.Tip1Image.Tooltip = {'Table should have 2 columns: attenuation_dBm, voltage_V in this order.'; 'Note that values should form a function V = f(dBm) for better approximation. You can test yourself by looking at the plot below.'};
            app.Tip1Image.ImageSource = 'gu_help.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appAddDevice_ATTENniMyDaq(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end