classdef appAddDevice_OPMrubin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OPMRubinUIFigure               matlab.ui.Figure
        ConnectSwitch                  matlab.ui.control.Switch
        ConnectPanel                   matlab.ui.container.Panel
        OPMnameEditField               matlab.ui.control.EditField
        OPMnameEditFieldLabel          matlab.ui.control.Label
        SerialportDropDown             matlab.ui.control.DropDown
        SerialportDropDownLabel        matlab.ui.control.Label
        RefreshButton                  matlab.ui.control.Button
        FunctionPanel                  matlab.ui.container.Panel
        nmLabel                        matlab.ui.control.Label
        WUnitsDropDown                 matlab.ui.control.DropDown
        SaveButton                     matlab.ui.control.Button
        dBmLabel                       matlab.ui.control.Label
        PowerWEditField                matlab.ui.control.NumericEditField
        PowerEditField                 matlab.ui.control.NumericEditField
        ReadpowerButton                matlab.ui.control.Button
        ChoosewavelengthDropDown       matlab.ui.control.DropDown
        ChoosewavelengthDropDownLabel  matlab.ui.control.Label
        OPMRubinLabel                  matlab.ui.control.Label
    end

    properties (Access = private)
        callingApp
        folder
    end
    properties (Access = public)
        mainObj
        deviceName
        WdBm
        Wsym
    end
    methods (Access = public)
        function refreshSerialportDropDown(app)
            app.SerialportDropDown.Items = serialportlist("available");
        end
        function checkOPMname(app)
            if isempty(app.OPMnameEditField.Value)
                app.OPMnameEditField.BackgroundColor = app.callingApp.ERROR_COLOR;
                output = 'Enter OPM name first';
                addStrOutput(app.callingApp,output);
                error(output);
            else
                app.OPMnameEditField.BackgroundColor = app.callingApp.DEFAULT_COLOR;
            end
        end
        function connect(app)
            try
                checkOPMname(app);
                app.deviceName = app.OPMnameEditField.Value;
                app.mainObj = [];
                appStruct = struct('OPMname', app.OPMnameEditField.Value,...
                    'Serialport',app.SerialportDropDown.Value);
                app.mainObj = Device_OPMrubin(appStruct);
                appendStructDevice_OPMrubin(app.callingApp,app,app.deviceName);
                getWavelengthArray(app);
                app.WUnitsDropDown.Items = app.mainObj.unitPrefixes;
                app.PowerEditField.Value = 0;
                app.PowerWEditField.Value = 0;
                output = strcat(app.deviceName," connected");
                addStrOutput(app.callingApp,output);
                currentWavelength = app.ChoosewavelengthDropDown.Value;
                output = strcat(app.deviceName," detect at ",...
                    currentWavelength, " nm");
                addStrOutput(app.callingApp,output);
                app.ConnectPanel.Enable = 'off';
                app.FunctionPanel.Enable = 'on';
            catch ME
                report = getReport(ME);
                uialert(app.callingApp.GreatUniterUIFigure,report,'Error Message','Interpreter','html');
                output = strcat(app.deviceName," not connected");
                addStrOutput(app.callingApp,output);
                app.ConnectSwitch.Value = "Disconnect";
                app.ConnectPanel.Enable = 'on';
                app.FunctionPanel.Enable = 'off';
            end
        end
        function disconnect(app)
            if ~isequal(class(app.mainObj),'double')
                app.mainObj = deleteVirtualObject(app.mainObj);
                output = strcat(app.deviceName," disconnected");
                addStrOutput(app.callingApp,output);
            end
            removeStructDevice_OPMrubin(app.callingApp,app.deviceName);
            app.ConnectPanel.Enable = 'on';
            app.FunctionPanel.Enable = 'off';
        end
        function getWavelengthArray(app)
            charArray = num2str(app.mainObj.wavelengthArray);
            cellArray = cellstr(charArray);
            app.ChoosewavelengthDropDown.Items = cellArray;
            app.ChoosewavelengthDropDown.Value = cellArray(1);
        end
        function selectWavelength(app)
            newWavelengthStr = app.ChoosewavelengthDropDown.Value;
            newWavelength = str2double(newWavelengthStr);
            app.mainObj = setWavelength(app.mainObj,newWavelength);
            output = strcat(app.deviceName," detect at ",...
                newWavelengthStr," nm");
            addStrOutput(app.callingApp,output);
        end
        function readPower(app)
            app.mainObj = requestPower(app.mainObj);
            app.WdBm = app.mainObj.powerdBm(end);
            app.Wsym = app.mainObj.powerWsym{end};

            output = strcat(app.deviceName," detect power ",...
                num2str(app.WdBm), " dBm");
            addStrOutput(app.callingApp,output);
        end
        function printWInUI(app)
            app.PowerEditField.Value = app.WdBm;
            [sNum,sUnits] = separateUnits(app.Wsym);
            app.PowerWEditField.Value = double(sNum);
            app.WUnitsDropDown.Value = symunit2str(sUnits);
        end
        function changeUnitPrefix(app,value)
            newsUnits = str2symunit(value);
            app.Wsym = unitConvert(app.Wsym,newsUnits);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
             app.callingApp = MainApp;
             app.folder = MainApp.folder;
             refreshSerialportDropDown(app);
        end

        % Value changed function: ConnectSwitch
        function ConnectSwitchValueChanged(app, event)
            value = app.ConnectSwitch.Value;
            if value == "Connect"
                connect(app);
            elseif value == "Disconnect"
                disconnect(app);
            end
        end

        % Button pushed function: ReadpowerButton
        function ReadpowerButtonPushed(app, event)
            readPower(app);
            printWInUI(app);
        end

        % Value changed function: OPMnameEditField
        function OPMnameEditFieldValueChanged(app, event)
            checkOPMname(app);
        end

        % Close request function: OPMRubinUIFigure
        function OPMRubinUIFigureCloseRequest(app, event)
            removeStructDevice_OPMrubin(app.callingApp,app.deviceName);
            output = "Window closed (OPM Rubin)";
            addStrOutput(app.callingApp,output);
            delete(app);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            refreshSerialportDropDown(app);
        end

        % Value changed function: ChoosewavelengthDropDown
        function ChoosewavelengthDropDownValueChanged(app, event)
            selectWavelength(app);
        end

        % Value changed function: WUnitsDropDown
        function WUnitsDropDownValueChanged(app, event)
            value = app.WUnitsDropDown.Value;
            changeUnitPrefix(app,value);
            printWInUI(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OPMRubinUIFigure = uifigure('Visible', 'off');
            app.OPMRubinUIFigure.Name = 'OPM Rubin';
            app.OPMRubinUIFigure.Icon = 'gu_device.png';
            app.OPMRubinUIFigure.CloseRequestFcn = createCallbackFcn(app, @OPMRubinUIFigureCloseRequest, true);
            app.OPMRubinLabel = uilabel(app.OPMRubinUIFigure);
            app.OPMRubinLabel.Text = 'OPM (Rubin)';
            app.FunctionPanel = uipanel(app.OPMRubinUIFigure);
            app.FunctionPanel.Enable = 'off';
            app.ChoosewavelengthDropDownLabel = uilabel(app.FunctionPanel);
            app.ChoosewavelengthDropDownLabel.Text = 'Choose wavelength';
            app.ChoosewavelengthDropDown = uidropdown(app.FunctionPanel);
            app.ChoosewavelengthDropDown.Items = {'1111', '2222', '3333'};
            app.ChoosewavelengthDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosewavelengthDropDownValueChanged, true);
            app.ChoosewavelengthDropDown.Value = '1111';
            app.ReadpowerButton = uibutton(app.FunctionPanel, 'push');
            app.ReadpowerButton.ButtonPushedFcn = createCallbackFcn(app, @ReadpowerButtonPushed, true);
            app.ReadpowerButton.Text = 'Read power';
            app.PowerEditField = uieditfield(app.FunctionPanel, 'numeric');
            app.PowerEditField.Editable = 'off';
            app.PowerWEditField = uieditfield(app.FunctionPanel, 'numeric');
            app.PowerWEditField.Editable = 'off';
            app.dBmLabel = uilabel(app.FunctionPanel);
            app.dBmLabel.Text = 'dBm';
            app.SaveButton = uibutton(app.FunctionPanel, 'push');
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.WUnitsDropDown = uidropdown(app.FunctionPanel);
            app.WUnitsDropDown.Items = {'nW', 'uW', 'mW', 'W'};
            app.WUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @WUnitsDropDownValueChanged, true);
            app.WUnitsDropDown.Value = 'mW';
            app.nmLabel = uilabel(app.FunctionPanel);
            app.nmLabel.Text = 'nm';
            app.ConnectPanel = uipanel(app.OPMRubinUIFigure);
            app.RefreshButton = uibutton(app.ConnectPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Icon = 'gu_refresh.png';
            app.RefreshButton.Text = '';
            app.SerialportDropDownLabel = uilabel(app.ConnectPanel);
            app.SerialportDropDownLabel.Text = 'Serial port';
            app.SerialportDropDown = uidropdown(app.ConnectPanel);
            app.SerialportDropDown.Items = {'COMXX'};
            app.SerialportDropDown.Value = 'COMXX';
            app.OPMnameEditFieldLabel = uilabel(app.ConnectPanel);
            app.OPMnameEditFieldLabel.Text = 'OPM name';
            app.OPMnameEditField = uieditfield(app.ConnectPanel, 'text');
            app.OPMnameEditField.ValueChangedFcn = createCallbackFcn(app, @OPMnameEditFieldValueChanged, true);
            app.ConnectSwitch = uiswitch(app.OPMRubinUIFigure, 'slider');
            app.ConnectSwitch.Items = {'Disconnect', 'Connect'};
            app.ConnectSwitch.ValueChangedFcn = createCallbackFcn(app, @ConnectSwitchValueChanged, true);
            app.ConnectSwitch.Value = 'Disconnect';
            % Show the figure after all components are created
            app.OPMRubinUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appAddDevice_OPMrubin(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OPMRubinUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OPMRubinUIFigure)
        end
    end
end