classdef appAddDevice_OSAyokogawa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OSAYokogawaUIFigure            matlab.ui.Figure
        Panel_2                        matlab.ui.container.Panel
        TextforfileEditField           matlab.ui.control.EditField
        TextforfileEditFieldLabel      matlab.ui.control.Label
        downloadButton                 matlab.ui.control.Button
        AddtableforautotestsTextArea   matlab.ui.control.TextArea
        AddtableforautotestsTextAreaLabel  matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        WaveformtraceDropDown          matlab.ui.control.DropDown
        WaveformtraceDropDownLabel     matlab.ui.control.Label
        ReferencetraceDropDown         matlab.ui.control.DropDown
        ReferencetraceDropDownLabel    matlab.ui.control.Label
        TimeoutEditField               matlab.ui.control.NumericEditField
        TimeoutEditFieldLabel          matlab.ui.control.Label
        IPaddressEditField             matlab.ui.control.EditField
        IPaddressEditFieldLabel        matlab.ui.control.Label
        Switch                         matlab.ui.control.Switch
        PanelFunction                  matlab.ui.container.Panel
        Tree                           matlab.ui.container.Tree
        BasicfunctionsNode             matlab.ui.container.TreeNode
        WritereferencetraceNode        matlab.ui.container.TreeNode
        WritewaveformNode              matlab.ui.container.TreeNode
        ReadwaveformNode               matlab.ui.container.TreeNode
        ReadanalysisEDFANFNode         matlab.ui.container.TreeNode
        ReadpowerNode                  matlab.ui.container.TreeNode
        AutofunctionsNode              matlab.ui.container.TreeNode
        WritewaveformloopEDFANFNode    matlab.ui.container.TreeNode
        WritewffpgaloopEDFANFNode      matlab.ui.container.TreeNode
        CustomfunctionsNode            matlab.ui.container.TreeNode
        WriterefpowerNode              matlab.ui.container.TreeNode
        WritewaveformEDFANFNode        matlab.ui.container.TreeNode
        SelectfunctionLabel            matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        PanelOffset                    matlab.ui.container.Panel
        OutoffsetEditField             matlab.ui.control.EditField
        OutoffsetEditFieldLabel        matlab.ui.control.Label
        InoffsetEditField              matlab.ui.control.EditField
        InoffsetEditFieldLabel         matlab.ui.control.Label
        PoweroffsetEditField           matlab.ui.control.EditField
        PoweroffsetEditFieldLabel      matlab.ui.control.Label
        dBLabel_4                      matlab.ui.control.Label
        dBLabel_3                      matlab.ui.control.Label
        dBLabel_2                      matlab.ui.control.Label
        CheckBoxOutOffset              matlab.ui.control.CheckBox
        CheckBoxInOffset               matlab.ui.control.CheckBox
        CheckBoxPowerOffset            matlab.ui.control.CheckBox
        ApplyoffsetsButton             matlab.ui.control.Button
        PanelSettings                  matlab.ui.container.Panel
        StopwavelengthEditField        matlab.ui.control.EditField
        StopwavelengthEditFieldLabel   matlab.ui.control.Label
        LevelscaleEditField            matlab.ui.control.EditField
        LevelscaleEditFieldLabel       matlab.ui.control.Label
        ReflevelEditField              matlab.ui.control.EditField
        ReflevelEditFieldLabel         matlab.ui.control.Label
        ResolutionEditField            matlab.ui.control.EditField
        ResolutionEditFieldLabel       matlab.ui.control.Label
        SensemodeEditField             matlab.ui.control.EditField
        SensemodeEditFieldLabel        matlab.ui.control.Label
        StartwavelengthEditField       matlab.ui.control.EditField
        StartwavelengthEditFieldLabel  matlab.ui.control.Label
        dBDLabel                       matlab.ui.control.Label
        dBmLabel                       matlab.ui.control.Label
        CheckBoxLevelScale             matlab.ui.control.CheckBox
        CheckBoxReferenceLevel         matlab.ui.control.CheckBox
        CheckBoxResolution             matlab.ui.control.CheckBox
        CheckBoxSenseMode              matlab.ui.control.CheckBox
        CheckBoxStopWl                 matlab.ui.control.CheckBox
        CheckBoxStartWl                matlab.ui.control.CheckBox
        ApplysettingsButton            matlab.ui.control.Button
        nmLabel_3                      matlab.ui.control.Label
        nmLabel_2                      matlab.ui.control.Label
        nmLabel                        matlab.ui.control.Label
        OpticalspectrumanalyzerYokogawaLabel  matlab.ui.control.Label
    end

    properties (Access = public)
        callingApp
        folder
        userText
    end
    properties (Access = public)
        osa
        fpga
        switchOsaEdfa
    end
    properties (Access = public)
        tableName
        tableAutoFunction
        tableAutoFunctionPath
    end
    methods (Access = private)
        function output = connect(app)
            %             try
            app.osa = [];
            app.osa = OpticalSpectrumAnalyzer(app);
            output = 'OSA connected';
            app.PanelFunction.Enable = 'on';
            app.PanelOffset.Enable = 'on';
            app.PanelSettings.Enable = 'on';
            %             catch ME
            %                 fig = uifigure;
            %                 output = "Connection failed";
            %                 uialert(fig,ME.message,output);
            %                 app.Switch.Value = "Disconnect";
            %             end
        end
        function output = disconnect(app)
            if ~isequal(class(app.osa),'double')
                app.osa = deleteVirtualObject(app.osa);
                output = 'OSA disconnected';
                app.PanelFunction.Enable = 'off';
                app.PanelOffset.Enable = 'off';
                app.PanelSettings.Enable = 'off';
            else
                output = 'не был подключен';
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.callingApp = MainApp;
            app.folder = MainApp.folder;

%             if ~isempty(MainApp.calledAppSWITCH)
%                 app.switchOsaEdfa = MainApp.calledAppSWITCH.switchOsaEdfa;
%             end
%             if ~isempty(MainApp.calledAppFPGA)
%                 app.fpga = MainApp.calledAppFPGA.fpga;
%             end
        end

        % Button pushed function: ApplyoffsetsButton
        function ApplyoffsetsButtonPushed(app, event)
            if app.CheckBoxPowerOffset.Value
                powerOffset = app.PoweroffsetEditField.Value;
                applyOffsetSettings(app.osa,powerOffset,'power');
            end
            if app.CheckBoxInOffset.Value
                inOffset = app.InoffsetEditField.Value;
                applyOffsetSettings(app.osa,inOffset,'in');
            end
            if app.CheckBoxOutOffset.Value
                outOffset = app.OutoffsetEditField.Value;
                applyOffsetSettings(app.osa,outOffset,'out');
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            clc;
            if value == "Connect"
                output = connect(app);
                app.tableAutoFunctionPath = 'OSAautoFunction.xlsx';
            elseif value == "Disconnect"
                output = disconnect(app);
            end
            addStrOutput(app.callingApp,output);
        end

        % Button pushed function: ApplysettingsButton
        function ApplysettingsButtonPushed(app, event)
            app.osa = applySettings(app.osa,app);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            value = app.Tree.SelectedNodes;
            if value == app.WritereferencetraceNode
                if ~isempty(app.switchOsaEdfa)
                    app.switchOsaEdfa = switchSignalOSA(app.switchOsaEdfa);
                end
                writeReferenceTrace(app.osa);
                output = 'Write reference waveform: success';
            elseif value == app.WritewaveformNode
                if ~isempty(app.switchOsaEdfa)
                    app.switchOsaEdfa = switchSignalEDFA(app.switchOsaEdfa);
                end
                writeWaveformTrace(app.osa);
                output = 'Write waveform: success';
            elseif value == app.ReadwaveformNode
                app.osa = readWaveform(app.osa);
                strForWaveform = '';%app.osa.waveformFile;%%%
                app.osa = saveWaveformToPC(app.osa,strForWaveform);
                plotWaveformYoko(app.osa,app.callingApp);
                output = 'Read waveform: success';
            elseif value == app.ReadanalysisEDFANFNode
                app.osa = readAnalysisEDFANF(app.osa);
                strForWaveform = '';
                saveAnalysisEDFANF(app.osa,strForWaveform);
                output = 'Read analysis EDFA-NF: success';
            elseif value == app.ReadpowerNode
                app.osa = readPower(app.osa);
                savePower(app.osa);
                powerValue = app.osa.power(end);
                powerStr = num2strForPrint(powerValue);
                output = ['Read power = ' powerStr];
            elseif value == app.WritewaveformEDFANFNode
                if ~isempty(app.switchOsaEdfa)
                    app.switchOsaEdfa = switchSignalEDFA(app.switchOsaEdfa);
                end
                writeWaveformTrace(app.osa);
                output = 'Write waveform: success';
                addStrOutput(app.callingApp,output);
                app.osa = readWaveform(app.osa);
                strForWaveform = '';%app.osa.waveformFile;%%%
                app.osa = saveWaveformToPC(app.osa,strForWaveform);
                plotWaveformYoko(app.osa,app.callingApp);
                output = 'Read waveform: success';
                addStrOutput(app.callingApp,output);
                %                 strForWaveform = '';%app.osa.waveformFile;%%%
                %                 app.osa = saveWaveformToPC(app.osa,strForWaveform);
                %                 plotWaveformYoko(app.osa,app.callingApp);
                app.osa = readAnalysisEDFANF(app.osa);
                app.osa = readPower(app.osa);
                savePower(app.osa);
                strForWaveform = '';
                saveAnalysisEDFANF(app.osa,strForWaveform);
                newRow = {'Максимальный ШФ',app.osa.maxNF};
                appendMainTable(app.callingApp,newRow);
                newRow = {'Средний КУ',app.osa.meanGAIN};
                appendMainTable(app.callingApp,newRow);
                newRow = {'Нелинейность',app.osa.deltaGAIN};
                appendMainTable(app.callingApp,newRow);
                newRow = {'Выходная мощность, дБм',app.osa.power(end)};
                appendMainTable(app.callingApp,newRow);
                output = 'finished';
            elseif value == app.WriterefpowerNode
                if ~isempty(app.switchOsaEdfa)
                    app.switchOsaEdfa = switchSignalOSA(app.switchOsaEdfa);
                end
                writeReferenceTrace(app.osa);
                app.osa = readAndSaveReference(app.osa);
                %app.osa = readPower(app.osa);
                output = num2str(readReferencePower(app.osa));
            elseif value == app.WritewaveformloopEDFANFNode
                app.tableAutoFunction= readtable(app.tableAutoFunctionPath,...
                    'Sheet','Sample','ReadRowNames',false,'ReadVariableNames',true);
                strForWaveform = app.TextforfileEditField.Value;%app.osa.waveformFile;%%%
                app.tableName = [createFilename(strForWaveform,now) '.xlsx'];
                if ~isempty(app.switchOsaEdfa)
                    app.switchOsaEdfa = switchSignalEDFA(app.switchOsaEdfa);
                end
                for i = 1:height(app.tableAutoFunction)
                    newRow = {'Номер измерения',i};
                    appendMainTable(app.callingApp,newRow);
                    writeWaveformTrace(app.osa);
                    output = 'Write waveform: success';
                    addStrOutput(app.callingApp,output);
                    app.osa = readWaveform(app.osa);
                    app.osa = saveWaveformToPC(app.osa,strForWaveform);
                    plotWaveformYoko(app.osa,app.callingApp);
                    output = 'Read waveform: success';
                    addStrOutput(app.callingApp,output);
                    app.osa = readAnalysisEDFANF(app.osa);
                    app.osa = readPower(app.osa);
                    app.tableAutoFunction{i,'powerDBm'} = app.osa.power(end);%{}
                    %savePower(app.osa);%%%
                    %strForWaveform = '';
                    saveAnalysisEDFANF(app.osa,strForWaveform);
                    newRow = {'Минимальный ШФ',app.osa.minNF};
                    appendMainTable(app.callingApp,newRow);
                    app.tableAutoFunction{i,'minNF'} = app.osa.minNF;
                    newRow = {'Средний ШФ',app.osa.meanNF};
                    appendMainTable(app.callingApp,newRow);
                    app.tableAutoFunction{i,'meanNF'} = app.osa.meanNF;
                    newRow = {'Максимальный ШФ',app.osa.maxNF};
                    appendMainTable(app.callingApp,newRow);
                    app.tableAutoFunction{i,'maxNF'} = app.osa.maxNF;
                    newRow = {'Минимальный КУ',app.osa.minGAIN};
                    appendMainTable(app.callingApp,newRow);
                    app.tableAutoFunction{i,'minGain'} = app.osa.minGAIN;
                    newRow = {'Средний КУ',app.osa.meanGAIN};
                    appendMainTable(app.callingApp,newRow);
                    app.tableAutoFunction{i,'meanGain'} = app.osa.meanGAIN;
                    newRow = {'Максимальный КУ',app.osa.maxGAIN};
                    appendMainTable(app.callingApp,newRow);
                    app.tableAutoFunction{i,'maxGain'} = app.osa.maxGAIN;
                    newRow = {'Нелинейность',app.osa.deltaGAIN};
                    appendMainTable(app.callingApp,newRow);
                    app.tableAutoFunction{i,'deltaGain'} = app.osa.deltaGAIN;
                    %disp(string(createFilename('',app.osa.time(end))))
                    %app.tableAutoFunction{i,'timecode'} = string(createFilename('',app.osa.time(end)));
                    pause(app.tableAutoFunction{i,'timeDelayS'});
                    writetable(app.tableAutoFunction,[app.folder app.tableName '.xlsx'],'AutoFitWidth',false);
                end
                
                output = 'finished';
            elseif value == app.WritewffpgaloopEDFANFNode
                app.tableAutoFunction= readtable(app.tableAutoFunctionPath,...
                    'Sheet','Sample','ReadRowNames',false,'ReadVariableNames',true);
                strForWaveform = app.TextforfileEditField.Value;%app.osa.waveformFile;%%%
                app.tableName = [createFilename(strForWaveform,now) '.xlsx'];
                if ~isempty(app.switchOsaEdfa)
                    app.switchOsaEdfa = switchSignalEDFA(app.switchOsaEdfa);
                end
                for i = 1:height(app.tableAutoFunction)
                    newRow = {'Номер измерения',i};
                    appendMainTable(app.callingApp,newRow);
                    writeWaveformTrace(app.osa);
                    output = 'Write waveform: success';
                    addStrOutput(app.callingApp,output);
                    app.osa = readWaveform(app.osa);
                    app.osa = saveWaveformToPC(app.osa,strForWaveform);
                    plotWaveformYoko(app.osa,app.callingApp);
                    output = 'Read waveform: success';
                    addStrOutput(app.callingApp,output);
                    app.osa = readAnalysisEDFANF(app.osa);
                    app.osa = readPower(app.osa);
                    app.tableAutoFunction{i,'powerDBm'} = app.osa.power(end);%{}
                    %savePower(app.osa);%%%
                    %strForWaveform = '';
                    saveAnalysisEDFANF(app.osa,strForWaveform);
%                     newRow = {'Минимальный ШФ',app.osa.minNF};
%                     appendMainTable(app.callingApp,newRow);
%                     app.tableAutoFunction{i,'minNF'} = app.osa.minNF;
%                     newRow = {'Средний ШФ',app.osa.meanNF};
%                     appendMainTable(app.callingApp,newRow);
%                     app.tableAutoFunction{i,'meanNF'} = app.osa.meanNF;
                    newRow = {'Максимальный ШФ',app.osa.maxNF};
                    appendMainTable(app.callingApp,newRow);
                    app.tableAutoFunction{i,'maxNF'} = app.osa.maxNF;
%                     newRow = {'Минимальный КУ',app.osa.minGAIN};
%                     appendMainTable(app.callingApp,newRow);
%                     app.tableAutoFunction{i,'minGain'} = app.osa.minGAIN;
%                     newRow = {'Средний КУ',app.osa.meanGAIN};
%                     appendMainTable(app.callingApp,newRow);
%                     app.tableAutoFunction{i,'meanGain'} = app.osa.meanGAIN;
%                     newRow = {'Максимальный КУ',app.osa.maxGAIN};
%                     appendMainTable(app.callingApp,newRow);
%                     app.tableAutoFunction{i,'maxGain'} = app.osa.maxGAIN;
%                     newRow = {'Нелинейность',app.osa.deltaGAIN};
%                     appendMainTable(app.callingApp,newRow);
%                     app.tableAutoFunction{i,'deltaGain'} = app.osa.deltaGAIN;
                    app.tableAutoFunction{i,'timecodeHHMMSS'} = str2double(datestr(app.osa.time(end), 'HHMMSS'));
                    newRow = {'timecodeHHMMSS',str2double(datestr(app.osa.time(end), 'HHMMSS'))};
                    appendMainTable(app.callingApp,newRow);

                    varNames = app.tableAutoFunction.Properties.VariableNames;
                    [logic, index] = ismember('timecodeHHMMSS',varNames);

                    for j = (index+1):width(app.tableAutoFunction)
                        FPGAname = varNames{j};
                        app.fpga = readDataFromFPGA(app.fpga,FPGAname);
                        app.tableAutoFunction{i,FPGAname} = str2double(app.fpga.lastRead);
                        newRow = {FPGAname,str2double(app.fpga.lastRead)};
                        appendMainTable(app.callingApp,newRow);
                        pause(0.5);
                    end
                    writetable(app.tableAutoFunction,[app.folder app.tableName '.xlsx'],'AutoFitWidth',false);
                    pause(app.tableAutoFunction{i,'timeDelayS'});
                    
                end
                output = 'finished';
            end
            addStrOutput(app.callingApp,output);
        end

        % Button pushed function: downloadButton
        function downloadButtonPushed(app, event)
            [file,path] = uigetfile('*.xlsx','Select table for autoFunction','OSAautoFunction.xlsx');
            if isequal(file,0)
                output = "";
            else
                output = [path file];
            end
            figure(app.OSAYokogawaUIFigure);
            app.tableAutoFunctionPath = output;
            app.AddtableforautotestsTextArea.Value = output;

            %app.tableAutoFunction = readtable(app.tableAutoFunctionPath,...
            %                     'Sheet','Sample','ReadVariableNames',true);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OSAYokogawaUIFigure = uifigure('Visible', 'off');
            app.OSAYokogawaUIFigure.Name = 'OSA Yokogawa';
            app.OSAYokogawaUIFigure.Icon = 'gu_device.png';
            app.OpticalspectrumanalyzerYokogawaLabel = uilabel(app.OSAYokogawaUIFigure);
            app.OpticalspectrumanalyzerYokogawaLabel.Text = 'Optical spectrum analyzer (Yokogawa)';
            app.PanelSettings = uipanel(app.OSAYokogawaUIFigure);
            app.PanelSettings.Enable = 'off';
            app.nmLabel = uilabel(app.PanelSettings);
            app.nmLabel.Text = 'nm';
            app.nmLabel_2 = uilabel(app.PanelSettings);
            app.nmLabel_2.Text = 'nm';
            app.nmLabel_3 = uilabel(app.PanelSettings);
            app.nmLabel_3.Text = 'nm';
            app.ApplysettingsButton = uibutton(app.PanelSettings, 'push');
            app.ApplysettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplysettingsButtonPushed, true);
            app.ApplysettingsButton.Text = 'Apply settings';
            app.CheckBoxStartWl = uicheckbox(app.PanelSettings);
            app.CheckBoxStartWl.Text = '';
            app.CheckBoxStartWl.Value = true;
            app.CheckBoxStopWl = uicheckbox(app.PanelSettings);
            app.CheckBoxStopWl.Text = '';
            app.CheckBoxStopWl.Value = true;
            app.CheckBoxSenseMode = uicheckbox(app.PanelSettings);
            app.CheckBoxSenseMode.Text = '';
            app.CheckBoxSenseMode.Value = true;
            app.CheckBoxResolution = uicheckbox(app.PanelSettings);
            app.CheckBoxResolution.Text = '';
            app.CheckBoxResolution.Value = true;
            app.CheckBoxReferenceLevel = uicheckbox(app.PanelSettings);
            app.CheckBoxReferenceLevel.Text = '';
            app.CheckBoxReferenceLevel.Value = true;
            app.CheckBoxLevelScale = uicheckbox(app.PanelSettings);
            app.CheckBoxLevelScale.Text = '';
            app.CheckBoxLevelScale.Value = true;
            app.dBmLabel = uilabel(app.PanelSettings);
            app.dBmLabel.Text = 'dBm';
            app.dBDLabel = uilabel(app.PanelSettings);
            app.dBDLabel.Text = 'dB/D';
            app.StartwavelengthEditFieldLabel = uilabel(app.PanelSettings);
            app.StartwavelengthEditFieldLabel.Text = 'Start wavelength';
            app.StartwavelengthEditField = uieditfield(app.PanelSettings, 'text');
            app.StartwavelengthEditField.Value = '1547';
            app.SensemodeEditFieldLabel = uilabel(app.PanelSettings);
            app.SensemodeEditFieldLabel.Text = 'Sense mode';
            app.SensemodeEditField = uieditfield(app.PanelSettings, 'text');
            app.SensemodeEditField.Value = 'HIGH1';
            app.ResolutionEditFieldLabel = uilabel(app.PanelSettings);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.PanelSettings, 'text');
            app.ResolutionEditField.Value = '0.1';
            app.ReflevelEditFieldLabel = uilabel(app.PanelSettings);
            app.ReflevelEditFieldLabel.Text = 'Ref level';
            app.ReflevelEditField = uieditfield(app.PanelSettings, 'text');
            app.ReflevelEditField.Value = '3';
            app.LevelscaleEditFieldLabel = uilabel(app.PanelSettings);
            app.LevelscaleEditFieldLabel.Text = 'Level scale';
            app.LevelscaleEditField = uieditfield(app.PanelSettings, 'text');
            app.LevelscaleEditField.Value = '10';
            app.StopwavelengthEditFieldLabel = uilabel(app.PanelSettings);
            app.StopwavelengthEditFieldLabel.Text = 'Stop wavelength';
            app.StopwavelengthEditField = uieditfield(app.PanelSettings, 'text');
            app.StopwavelengthEditField.Value = '1560';
            app.PanelOffset = uipanel(app.OSAYokogawaUIFigure);
            app.PanelOffset.Enable = 'off';
            app.ApplyoffsetsButton = uibutton(app.PanelOffset, 'push');
            app.ApplyoffsetsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyoffsetsButtonPushed, true);
            app.ApplyoffsetsButton.Text = 'Apply offsets';
            app.CheckBoxPowerOffset = uicheckbox(app.PanelOffset);
            app.CheckBoxPowerOffset.Text = '';
            app.CheckBoxPowerOffset.Value = true;
            app.CheckBoxInOffset = uicheckbox(app.PanelOffset);
            app.CheckBoxInOffset.Text = '';
            app.CheckBoxInOffset.Value = true;
            app.CheckBoxOutOffset = uicheckbox(app.PanelOffset);
            app.CheckBoxOutOffset.Text = '';
            app.CheckBoxOutOffset.Value = true;
            app.dBLabel_2 = uilabel(app.PanelOffset);
            app.dBLabel_2.Text = 'dB';
            app.dBLabel_3 = uilabel(app.PanelOffset);
            app.dBLabel_3.Text = 'dB';
            app.dBLabel_4 = uilabel(app.PanelOffset);
            app.dBLabel_4.Text = 'dB';
            app.PoweroffsetEditFieldLabel = uilabel(app.PanelOffset);
            app.PoweroffsetEditFieldLabel.Text = 'Power offset';
            app.PoweroffsetEditField = uieditfield(app.PanelOffset, 'text');
            app.PoweroffsetEditField.Value = '0';
            app.InoffsetEditFieldLabel = uilabel(app.PanelOffset);
            app.InoffsetEditFieldLabel.Text = 'In offset';
            app.InoffsetEditField = uieditfield(app.PanelOffset, 'text');
            app.InoffsetEditField.Value = '0';
            app.OutoffsetEditFieldLabel = uilabel(app.PanelOffset);
            app.OutoffsetEditFieldLabel.Text = 'Out offset';
            app.OutoffsetEditField = uieditfield(app.PanelOffset, 'text');
            app.OutoffsetEditField.Value = '0';
            app.PanelFunction = uipanel(app.OSAYokogawaUIFigure);
            app.PanelFunction.Enable = 'off';
            app.RunButton = uibutton(app.PanelFunction, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SelectfunctionLabel = uilabel(app.PanelFunction);
            app.SelectfunctionLabel.Text = 'Select function';
            app.Tree = uitree(app.PanelFunction);
            app.BasicfunctionsNode = uitreenode(app.Tree);
            app.BasicfunctionsNode.Text = 'Basic functions';
            app.WritereferencetraceNode = uitreenode(app.BasicfunctionsNode);
            app.WritereferencetraceNode.Text = 'Write reference trace';
            app.WritewaveformNode = uitreenode(app.BasicfunctionsNode);
            app.WritewaveformNode.Text = 'Write waveform';
            app.ReadwaveformNode = uitreenode(app.BasicfunctionsNode);
            app.ReadwaveformNode.Text = 'Read waveform';
            app.ReadanalysisEDFANFNode = uitreenode(app.BasicfunctionsNode);
            app.ReadanalysisEDFANFNode.Text = 'Read analysis EDFA-NF';
            app.ReadpowerNode = uitreenode(app.BasicfunctionsNode);
            app.ReadpowerNode.Text = 'Read power';
            app.AutofunctionsNode = uitreenode(app.Tree);
            app.AutofunctionsNode.Text = 'Auto functions';
            app.WritewaveformloopEDFANFNode = uitreenode(app.AutofunctionsNode);
            app.WritewaveformloopEDFANFNode.Text = 'Write waveform (loop), EDFA-NF';
            app.WritewffpgaloopEDFANFNode = uitreenode(app.AutofunctionsNode);
            app.WritewffpgaloopEDFANFNode.Text = 'Write wf+fpga (loop), EDFA-NF';
            app.CustomfunctionsNode = uitreenode(app.Tree);
            app.CustomfunctionsNode.Text = 'Custom functions';
            app.WriterefpowerNode = uitreenode(app.CustomfunctionsNode);
            app.WriterefpowerNode.Text = 'Write ref, power';
            app.WritewaveformEDFANFNode = uitreenode(app.CustomfunctionsNode);
            app.WritewaveformEDFANFNode.Text = 'Write waveform, EDFA-NF';
            app.Panel = uipanel(app.OSAYokogawaUIFigure);
            app.Switch = uiswitch(app.Panel, 'slider');
            app.Switch.Items = {'Disconnect', 'Connect'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Disconnect';
            app.IPaddressEditFieldLabel = uilabel(app.Panel);
            app.IPaddressEditFieldLabel.Text = 'IP address';
            app.IPaddressEditField = uieditfield(app.Panel, 'text');
            app.IPaddressEditField.Value = '192.168.100.23';
            app.TimeoutEditFieldLabel = uilabel(app.Panel);
            app.TimeoutEditFieldLabel.Text = 'Time out';
            app.TimeoutEditField = uieditfield(app.Panel, 'numeric');
            app.TimeoutEditField.Value = 30;
            app.ReferencetraceDropDownLabel = uilabel(app.Panel);
            app.ReferencetraceDropDownLabel.Text = 'Reference trace';
            app.ReferencetraceDropDown = uidropdown(app.Panel);
            app.ReferencetraceDropDown.Items = {'A', 'B', 'C', 'D', 'E', 'F', 'G'};
            app.ReferencetraceDropDown.Value = 'A';
            app.WaveformtraceDropDownLabel = uilabel(app.Panel);
            app.WaveformtraceDropDownLabel.Text = 'Waveform trace';
            app.WaveformtraceDropDown = uidropdown(app.Panel);
            app.WaveformtraceDropDown.Items = {'A', 'B', 'C', 'D', 'E', 'F', 'G'};
            app.WaveformtraceDropDown.Value = 'B';
            app.Panel_2 = uipanel(app.OSAYokogawaUIFigure);
            app.AddtableforautotestsTextAreaLabel = uilabel(app.Panel_2);
            app.AddtableforautotestsTextAreaLabel.Text = 'Add table for auto tests';
            app.AddtableforautotestsTextArea = uitextarea(app.Panel_2);
            app.AddtableforautotestsTextArea.Value = {'OSAautoFunction.xlsx'};
            app.downloadButton = uibutton(app.Panel_2, 'push');
            app.downloadButton.ButtonPushedFcn = createCallbackFcn(app, @downloadButtonPushed, true);
            app.downloadButton.Text = '';
            app.TextforfileEditFieldLabel = uilabel(app.Panel_2);
            app.TextforfileEditFieldLabel.Text = 'Text for file';
            app.TextforfileEditField = uieditfield(app.Panel_2, 'text');
            app.TextforfileEditField.Value = 'edfaN1_test';
            % Show the figure after all components are created
            app.OSAYokogawaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appAddDevice_OSAyokogawa(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OSAYokogawaUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OSAYokogawaUIFigure)
        end
    end
end