classdef appAddDevice_SWITCHosaEdfa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        searchavailableserialportsButton  matlab.ui.control.Button
        COMportEditField         matlab.ui.control.EditField
        COMportEditFieldLabel    matlab.ui.control.Label
        SwitchpositionLabel      matlab.ui.control.Label
        ConfirmButton            matlab.ui.control.Button
        ButtonGroup              matlab.ui.container.ButtonGroup
        OSAButton                matlab.ui.control.RadioButton
        EDFAButton               matlab.ui.control.RadioButton
        SwitchOSAEDFA            matlab.ui.control.Switch
        FunctionPanel            matlab.ui.container.Panel
        Tip1Image                matlab.ui.control.Image
        SwitchSwitch             matlab.ui.control.RockerSwitch
        SubmitButton             matlab.ui.control.Button
        RequestEditField         matlab.ui.control.EditField
        Label_3                  matlab.ui.control.Label
        HeaderLabel              matlab.ui.control.Label
        ConnectPanel             matlab.ui.container.Panel
        RefreshButton            matlab.ui.control.Button
        SerialportDropDown       matlab.ui.control.DropDown
        SerialportDropDownLabel  matlab.ui.control.Label
        ConnectSwitch            matlab.ui.control.Switch
    end

    
    properties (Access = public)
        mainObj
        callingApp
    end
    
    methods (Access = private)

        function refreshSerialportDropDown(app)
            app.SerialportDropDown.Items = serialportlist("available");
        end

        function refreshSwitchSwitch(app)
            app.mainObj = requestSwitchState(app.mainObj);
            if app.mainObj.switchState == "OSA" || app.mainObj.switchState == "EDFA"
                app.SwitchSwitch.Value = app.mainObj.switchState;
            end
        end
        
        function saveToMemory(app)
            appAddDevice_SWITCHosaEdfa = struct('SerialportDropDown',app.SerialportDropDown.Value);
            save('TheGreatUniterMemory.mat','appAddDevice_SWITCHosaEdfa');
        end
        
        function loadFromMemory(app)
            load('TheGreatUniterMemory.mat','appAddDevice_SWITCHosaEdfa');
            app.SerialportDropDown.Value = appAddDevice_SWITCHosaEdfa.SerialportDropDown;
        end

        function output = connect(app)
            app.mainObj = [];
            app.mainObj = Device_SWITCHosaEdfa(app);
            output = 'SWITCH (OSA/EDFA) connected';
            app.FunctionPanel.Enable = "on";
        end

        function output = disconnect(app)
            if ~isequal(class(app.switchOsaEdfa),'double')
                app.mainObj = deleteVirtualObject(app.mainObj);
                output = 'SWITCH (OSA/EDFA) disconnected';
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
%            app.callingApp = MainApp;
loadFromMemory(app);
            refreshSerialportDropDown(app);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            refreshSerialportDropDown(app);
        end

        % Value changed function: ConnectSwitch
        function ConnectSwitchValueChanged(app, event)
            value = app.ConnectSwitch.Value;
            if value == "Connect"
                output = connect(app);
            elseif value == "Disconnect"
                output = disconnect(app);
            end
            printOutput(app.callingApp,output);
        end

        % Value changed function: SwitchSwitch
        function SwitchSwitchValueChanged(app, event)
            value = app.SwitchSwitch.Value;
            app.mainObj = requestSwitchState(app.mainObj);
            if value ~= app.mainObj.switchState
                app.mainObj = switchSignalTo(app.mainObj,value);
                app.SwitchSwitch.Value = app.mainObj.switchState;
                printOutput(app.callingApp,app.mainObj.infoString);
            end
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            requestCommand = app.RequestEditField.Value;
            sendCommand(app.mainObj,requestCommand);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            saveToMemory(app);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ConnectPanel = uipanel(app.UIFigure);
            app.ConnectSwitch = uiswitch(app.ConnectPanel, 'slider');
            app.ConnectSwitch.Items = {'Disconnect', 'Connect'};
            app.ConnectSwitch.ValueChangedFcn = createCallbackFcn(app, @ConnectSwitchValueChanged, true);
            app.ConnectSwitch.Value = 'Disconnect';
            app.SerialportDropDownLabel = uilabel(app.ConnectPanel);
            app.SerialportDropDownLabel.Text = 'Serial port';
            app.SerialportDropDown = uidropdown(app.ConnectPanel);
            app.SerialportDropDown.Items = {'COMXX'};
            app.SerialportDropDown.Value = 'COMXX';
            app.RefreshButton = uibutton(app.ConnectPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Icon = 'gu_refresh.png';
            app.RefreshButton.Text = '';
            app.HeaderLabel = uilabel(app.UIFigure);
            app.HeaderLabel.Text = 'Switch (OSA/EDFA)';
            app.FunctionPanel = uipanel(app.UIFigure);
            app.FunctionPanel.Enable = 'off';
            app.Label_3 = uilabel(app.FunctionPanel);
            app.Label_3.Text = 'Submit custom request';
            app.RequestEditField = uieditfield(app.FunctionPanel, 'text');
            app.RequestEditField.Tooltip = {'How to form a request:'; '1. Switch number (001,002 or 003)'; '2. Semicolon (;)'; '3. Switch state (0 or 1)'; '4. Semicolon (;)'; 'For example, 001;1; turn first switch on, you can see that first diode turn on.'};
            app.RequestEditField.Value = '003;1;';
            app.SubmitButton = uibutton(app.FunctionPanel, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Icon = 'gu_check.png';
            app.SubmitButton.Text = '';
            app.SwitchSwitch = uiswitch(app.FunctionPanel, 'rocker');
            app.SwitchSwitch.Items = {'OSA', 'EDFA'};
            app.SwitchSwitch.ValueChangedFcn = createCallbackFcn(app, @SwitchSwitchValueChanged, true);
            app.SwitchSwitch.Value = 'OSA';
            app.Tip1Image = uiimage(app.FunctionPanel);
            app.Tip1Image.Tooltip = {'Switch shows real state. If you change it not in program you would NOT see changes here.'};
            app.Tip1Image.ImageSource = 'gu_help.png';
            app.SwitchOSAEDFA = uiswitch(app.UIFigure, 'slider');
            app.SwitchOSAEDFA.Items = {'OSA', 'EDFA'};
            app.SwitchOSAEDFA.Enable = 'off';
            app.SwitchOSAEDFA.Value = 'EDFA';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.EDFAButton = uiradiobutton(app.ButtonGroup);
            app.EDFAButton.Text = 'EDFA';
            app.EDFAButton.Value = true;
            app.OSAButton = uiradiobutton(app.ButtonGroup);
            app.OSAButton.Text = 'OSA';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.Icon = 'icon_confirm.png';
            app.ConfirmButton.Tooltip = {'confirm'};
            app.ConfirmButton.Text = '';
            app.SwitchpositionLabel = uilabel(app.UIFigure);
            app.SwitchpositionLabel.Text = 'Switch position';
            app.COMportEditFieldLabel = uilabel(app.UIFigure);
            app.COMportEditFieldLabel.Text = 'COM-port';
            app.COMportEditField = uieditfield(app.UIFigure, 'text');
            app.COMportEditField.Value = 'COM3';
            app.searchavailableserialportsButton = uibutton(app.UIFigure, 'push');
            app.searchavailableserialportsButton.Tooltip = {'refresh'};
            app.searchavailableserialportsButton.Text = 'search available serial ports';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appAddDevice_SWITCHosaEdfa(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end