classdef appAddDevice_SWITCHosaEdfaTwoLines < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SWITCHOSAEDFAUIFigure        matlab.ui.Figure
        ConnectSwitch                matlab.ui.control.Switch
        FunctionPanel                matlab.ui.container.Panel
        Tip1Image                    matlab.ui.control.Image
        ForrequestcurrentstateLabel  matlab.ui.control.Label
        RefreshButton_2              matlab.ui.control.Button
        SwitchstateButtonGroup       matlab.ui.container.ButtonGroup
        EDFALine2Button              matlab.ui.control.ToggleButton
        EDFALine1Button              matlab.ui.control.ToggleButton
        OSAButton                    matlab.ui.control.ToggleButton
        SubmitButton                 matlab.ui.control.Button
        RequestEditField             matlab.ui.control.EditField
        Label_3                      matlab.ui.control.Label
        HeaderLabel                  matlab.ui.control.Label
        ConnectPanel                 matlab.ui.container.Panel
        RefreshButton                matlab.ui.control.Button
        SerialportDropDown           matlab.ui.control.DropDown
        SerialportDropDownLabel      matlab.ui.control.Label
    end

    
    properties (Access = public)
        mainObj
        callingApp
    end
    
    methods (Access = private)
        function refreshSerialportDropDown(app)
            app.SerialportDropDown.Items = serialportlist("available");
        end
        function refreshSwitchState(app)
            app.mainObj = requestSwitchState(app.mainObj);
            if app.mainObj.switchState == "OSA"
                app.OSAButton.Value = true;
            elseif app.mainObj.switchState == "EDFAFirstLine"
                app.EDFALine1Button.Value = true;
            elseif app.mainObj.switchState == "EDFASecondLine"
                app.EDFALine2Button.Value = true;
            end
        end
        function submitSwitchState(app)
            if app.OSAButton.Value
                OSAorEDFAstring = "OSA";
            elseif app.EDFALine1Button.Value
                OSAorEDFAstring = "EDFAFirstLine";
            elseif app.EDFALine2Button.Value
                OSAorEDFAstring = "EDFASecondLine";
            end
            app.mainObj = switchSignalTo(app.mainObj,OSAorEDFAstring);
            addStrOutput(app.callingApp,app.mainObj.infoString)
        end
        function connect(app)
            try
                app.mainObj = [];
                appStruct = struct('SerialportDropDown',app.SerialportDropDown.Value);
                app.mainObj = Device_SWITCHosaEdfaTwoLines(appStruct);
                output = "SWITCH (OSA/EDFA two lines) connected";
                addStrOutput(app.callingApp,output);
                app.ConnectPanel.Enable = 'off';
                app.FunctionPanel.Enable = 'on';
                refreshSwitchState(app);
            catch ME
                report = getReport(ME);
                uialert(app.callingApp.GreatUniterUIFigure,report,'Error Message');%,'Interpreter','html');
                output = "Wrong connection";
                addStrOutput(app.callingApp,output);
                app.ConnectSwitch.Value = "Disconnect";
                app.ConnectPanel.Enable = 'on';
                app.FunctionPanel.Enable = 'off';
            end
        end
        function disconnect(app)
            if ~isequal(class(app.mainObj),'double')
                app.mainObj = deleteVirtualObject(app.mainObj);
                output = "SWITCH (OSA/EDFA two lines) disconnected";
                addStrOutput(app.callingApp,output);
            end
            app.ConnectPanel.Enable = 'on';
            app.FunctionPanel.Enable = 'off';
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.callingApp = MainApp;
            refreshSerialportDropDown(app);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            refreshSerialportDropDown(app);
        end

        % Value changed function: ConnectSwitch
        function ConnectSwitchValueChanged(app, event)
            value = app.ConnectSwitch.Value;
            if value == "Connect"
                connect(app);
            elseif value == "Disconnect"
                disconnect(app);
            end
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            requestCommand = app.RequestEditField.Value;
            sendCommand(app.mainObj,requestCommand);
        end

        % Close request function: SWITCHOSAEDFAUIFigure
        function SWITCHOSAEDFAUIFigureCloseRequest(app, event)
            disconnect(app);
            delete(app);
        end

        % Button pushed function: RefreshButton_2
        function RefreshButton_2Pushed(app, event)
            refreshSwitchState(app);
        end

        % Selection changed function: SwitchstateButtonGroup
        function SwitchstateButtonGroupSelectionChanged(app, event)
            submitSwitchState(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SWITCHOSAEDFAUIFigure = uifigure('Visible', 'off');
            app.SWITCHOSAEDFAUIFigure.Name = 'SWITCH OSA/EDFA';
            app.SWITCHOSAEDFAUIFigure.Icon = 'gu_device.png';
            app.SWITCHOSAEDFAUIFigure.CloseRequestFcn = createCallbackFcn(app, @SWITCHOSAEDFAUIFigureCloseRequest, true);
            app.ConnectPanel = uipanel(app.SWITCHOSAEDFAUIFigure);
            app.SerialportDropDownLabel = uilabel(app.ConnectPanel);
            app.SerialportDropDownLabel.Text = 'Serial port';
            app.SerialportDropDown = uidropdown(app.ConnectPanel);
            app.SerialportDropDown.Items = {'COMXX'};
            app.SerialportDropDown.Value = 'COMXX';
            app.RefreshButton = uibutton(app.ConnectPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Icon = 'gu_refresh.png';
            app.RefreshButton.Text = '';
            app.HeaderLabel = uilabel(app.SWITCHOSAEDFAUIFigure);
            app.HeaderLabel.Text = 'Switch (OSA/EDFA two lines)';
            app.FunctionPanel = uipanel(app.SWITCHOSAEDFAUIFigure);
            app.FunctionPanel.Enable = 'off';
            app.Label_3 = uilabel(app.FunctionPanel);
            app.Label_3.Visible = 'off';
            app.Label_3.Text = 'Submit custom request';
            app.RequestEditField = uieditfield(app.FunctionPanel, 'text');
            app.RequestEditField.Visible = 'off';
            app.RequestEditField.Tooltip = {'How to form a request:'; '1. Switch number (001,002 or 003)'; '2. Semicolon (;)'; '3. Switch state (0 or 1)'; '4. Semicolon (;)'; 'For example, 001;1; turn first switch on, you can see that first diode turn on.'};
            app.RequestEditField.Value = '003;1;';
            app.SubmitButton = uibutton(app.FunctionPanel, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Icon = 'gu_check.png';
            app.SubmitButton.Visible = 'off';
            app.SubmitButton.Text = '';
            app.SwitchstateButtonGroup = uibuttongroup(app.FunctionPanel);
            app.SwitchstateButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SwitchstateButtonGroupSelectionChanged, true);
            app.SwitchstateButtonGroup.Title = 'Switch state';
            app.OSAButton = uitogglebutton(app.SwitchstateButtonGroup);
            app.OSAButton.Text = 'OSA';
            app.OSAButton.Value = true;
            app.EDFALine1Button = uitogglebutton(app.SwitchstateButtonGroup);
            app.EDFALine1Button.Text = 'EDFA Line 1';
            app.EDFALine2Button = uitogglebutton(app.SwitchstateButtonGroup);
            app.EDFALine2Button.Text = 'EDFA Line 2';
            app.RefreshButton_2 = uibutton(app.FunctionPanel, 'push');
            app.RefreshButton_2.ButtonPushedFcn = createCallbackFcn(app, @RefreshButton_2Pushed, true);
            app.RefreshButton_2.Icon = 'gu_refresh.png';
            app.RefreshButton_2.Text = '';
            app.ForrequestcurrentstateLabel = uilabel(app.FunctionPanel);
            app.ForrequestcurrentstateLabel.Text = 'For request current state';
            app.Tip1Image = uiimage(app.FunctionPanel);
            app.Tip1Image.Tooltip = {'If you change it not in program you would NOT see changes here.'};
            app.Tip1Image.ImageSource = 'gu_help.png';
            app.ConnectSwitch = uiswitch(app.SWITCHOSAEDFAUIFigure, 'slider');
            app.ConnectSwitch.Items = {'Disconnect', 'Connect'};
            app.ConnectSwitch.ValueChangedFcn = createCallbackFcn(app, @ConnectSwitchValueChanged, true);
            app.ConnectSwitch.Value = 'Disconnect';
            % Show the figure after all components are created
            app.SWITCHOSAEDFAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appAddDevice_SWITCHosaEdfaTwoLines(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SWITCHOSAEDFAUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SWITCHOSAEDFAUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SWITCHOSAEDFAUIFigure)
        end
    end
end