classdef appCalibrateEDFA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CalibrateEDFAUIFigure           matlab.ui.Figure
        ApplyButton                     matlab.ui.control.StateButton
        OffsetsPanel                    matlab.ui.container.Panel
        getoffsetsButton                matlab.ui.control.Button
        OffsetsavetableCheckBox         matlab.ui.control.CheckBox
        OffsetwritetoEEPROMCheckBox     matlab.ui.control.CheckBox
        OffsetwritetoFLASHCheckBox      matlab.ui.control.CheckBox
        OffsetTimesEditField            matlab.ui.control.NumericEditField
        TimestogetvaluesfromVDLabel     matlab.ui.control.Label
        OutCoeffsPanel                  matlab.ui.container.Panel
        OutDropDown                     matlab.ui.control.DropDown
        calibrateP_outButton            matlab.ui.control.Button
        mkWADCLabel_2                   matlab.ui.control.Label
        dBLabel_3                       matlab.ui.control.Label
        OutsavetableCheckBox            matlab.ui.control.CheckBox
        OutwritetoFLASHCheckBox         matlab.ui.control.CheckBox
        OutwritetoEEPROMCheckBox        matlab.ui.control.CheckBox
        Coeff_outEditField              matlab.ui.control.NumericEditField
        Coeff_outEditFieldLabel         matlab.ui.control.Label
        Loss_outEditField               matlab.ui.control.NumericEditField
        Loss_outEditFieldLabel          matlab.ui.control.Label
        InCoeffsPanel                   matlab.ui.container.Panel
        InDropDown                      matlab.ui.control.DropDown
        calibrateP_inButton             matlab.ui.control.Button
        mkWADCLabel                     matlab.ui.control.Label
        dBLabel_2                       matlab.ui.control.Label
        InsavetableCheckBox             matlab.ui.control.CheckBox
        InwritetoFLASHCheckBox          matlab.ui.control.CheckBox
        InwritetoEEPROMCheckBox         matlab.ui.control.CheckBox
        Coeff_inEditField               matlab.ui.control.NumericEditField
        Coeff_inEditFieldLabel          matlab.ui.control.Label
        Loss_inEditField                matlab.ui.control.NumericEditField
        Loss_inEditFieldLabel           matlab.ui.control.Label
        SettingsPanel                   matlab.ui.container.Panel
        SelectmodeDropDown              matlab.ui.control.DropDown
        Label_13                        matlab.ui.control.Label
        writeinitialcoeffstoFPGAButton  matlab.ui.control.Button
        readcoefficientsfromFPGAButton  matlab.ui.control.Button
        CheckCoeffsLabel                matlab.ui.control.Label
        dBLabel                         matlab.ui.control.Label
        downloadTableButton             matlab.ui.control.Button
        ZoomInOneButton                 matlab.ui.control.Button
        UserTextEditField               matlab.ui.control.EditField
        Label_3                         matlab.ui.control.Label
        PEditField                      matlab.ui.control.NumericEditField
        deltaPrubinLabel                matlab.ui.control.Label
        AssembleopticalcircuitentervaluePPPinPoutLabel  matlab.ui.control.Label
        CircuitImage                    matlab.ui.control.Image
        LineDropDown                    matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        tableTextArea                   matlab.ui.control.TextArea
        Label_2                         matlab.ui.control.Label
        HeaderLabel                     matlab.ui.control.Label
    end

    properties (Access = private)
        callingApp
        folder
    end
    properties (Access = public)
        FPGA
        ATTENniMyDaq
        SWITCHosaEdfa
        Pin
        Pout
    end

    properties
        tableAutoCalibPath
        %         tableAutoCalib
        rowNumberPin
        rowNumberPout
        opticLineNumber
        %tableVDforOFFSET
        tableInfo
        tableExtraInfo
        tableResultSample
        tablePin
        tablePout
        PINname
        POUTname
        nameVDout_1
        nameVDout_2
        nameVDin_1
        nameVDin_2
        N
        mode
    end

    methods (Access = private)
        function app = readTableInfo(app)
            app.tableInfo = readtable(app.tableAutoCalibPath,...
                'Sheet','Info','ReadRowNames',true);
        end
        function app = readTableResultSample(app)
            app.tableResultSample = readtable(app.tableAutoCalibPath,...
                'Sheet','ResultSample','ReadRowNames',true);
            disp(app.tableResultSample)
        end
        function saveTableResultSample(app)
            userText = app.UserTextEditField.Value;
            tableName = createFilename([userText 'Result_'], now);
            writetable(app.tableResultSample,[app.folder tableName '.xlsx'],'AutoFitWidth',false,"WriteRowNames",true);
        end
        function app = readTablePin(app)
            tableArray = readtable(app.tableAutoCalibPath,'Sheet','Pin');
            app.rowNumberPin = height(tableArray);

            app.tablePin = tableArray;
        end
        function saveTablePin(app)
            userText = app.UserTextEditField.Value;
            tableName = createFilename([userText 'VDin_'], now);
            writetable(app.tablePin,[app.folder tableName '.xlsx'],'AutoFitWidth',false);
        end
        function app = readTablePout(app)
            app.tablePout = readtable(app.tableAutoCalibPath,'Sheet','Pout');
            app.rowNumberPout = height(app.tablePout);
        end
        function saveTablePout(app)
            userText = app.UserTextEditField.Value;
            %Pinput = num2strForPrint();
            s = [userText 'VDout_']; %Pinput ];
            tableName = createFilename(s, now);
            writetable(app.tablePout,[app.folder tableName '.xlsx'],'AutoFitWidth',false);
        end
        function nameVD_1 = getNameVD1(app,P)
            rowName = [app.LineDropDown.Value '_1'];
            nameVD_1 = app.tableInfo{rowName,P};
        end
        function nameVD_2 = getNameVD2(app,P)
            rowName = [app.LineDropDown.Value '_2'];
            nameVD_2 = app.tableInfo{rowName,P};
        end
        function app = getMeanForOffset(app,VD)
            arrayForMean = zeros(1,app.N);
            for i = 1:app.N
                app.FPGA = readData(app.FPGA,VD);
                arrayForMean(i) = str2double(app.FPGA.lastRead);
            end
            offset = round(-1*mean(arrayForMean));
            app.tableResultSample{VD,'OFFSET_'} = offset;
            if app.OffsetwritetoFLASHCheckBox.Value
                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,['OFFSET_' VD], num2str(offset));
                addStrOutput(app.callingApp, ['in FLASH mem: OFFSET_',VD, ' = ',app.FPGA.lastRead])
            end
            if app.OffsetwritetoEEPROMCheckBox.Value
                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.EEPROM_MEM,['OFFSET_' VD],num2str(offset));
                addStrOutput(app.callingApp, ['in EEPROM mem: OFFSET_',VD, ' = ',app.FPGA.lastRead])
            end
            pause(0.5)
        end

        function loadFromMemFileToApp(app)
            load('TheGreatUniterMemory.mat','appCalibrateEDFA');
            app.PEditField.Value = appCalibrateEDFA.PEditField;
            app.tableTextArea.Value = appCalibrateEDFA.tableTextArea;
            app.UserTextEditField.Value = appCalibrateEDFA.UserTextEditField;
            app.OffsetTimesEditField.Value = appCalibrateEDFA.OffsetTimesEditField;
            app.Loss_inEditField.Value = appCalibrateEDFA.Loss_inEditField;
            app.Coeff_inEditField.Value = appCalibrateEDFA.Coeff_inEditField;
            app.Loss_outEditField.Value = appCalibrateEDFA.Loss_outEditField;
            app.Coeff_outEditField.Value = appCalibrateEDFA.Coeff_outEditField;
        end
        function saveFromAppToMemFile(app)
            appCalibrateEDFA.PEditField = app.PEditField.Value;
            appCalibrateEDFA.tableTextArea = app.tableTextArea.Value{1};
            appCalibrateEDFA.UserTextEditField = app.UserTextEditField.Value;
            appCalibrateEDFA.OffsetTimesEditField = app.OffsetTimesEditField.Value;
            appCalibrateEDFA.Loss_inEditField = app.Loss_inEditField.Value;
            appCalibrateEDFA.Coeff_inEditField = app.Coeff_inEditField.Value;
            appCalibrateEDFA.Loss_outEditField = app.Loss_outEditField.Value;
            appCalibrateEDFA.Coeff_outEditField = app.Coeff_outEditField.Value;
            save('TheGreatUniterMemory.mat','appCalibrateEDFA','-append');
        end
%         function checkFieldsNotEmpty(app)
%             fieldsArray = {...
%                 app.SelectComponentsTableTextArea,...
%                 app.SelectSampleTableTextArea,...
%                 app.BoardnumberEditField,...
%                 app.CommentEditField,...
%                 app.InspectornameEditField...
%                 };
%             for i = 1:length(fieldsArray)
%                 if iscell(fieldsArray{i}.Value)
%                     value = fieldsArray{i}.Value{1};
%                 else
%                     value = fieldsArray{i}.Value;
%                 end
%                 if isempty(value)
%                     fieldsArray{i}.BackgroundColor = app.callingApp.ERROR_COLOR;
%                     output = 'Fields must not be empty';
%                     addStrOutput(app.callingApp,output)
%                     error(output);
%                 else
%                     fieldsArray{i}.BackgroundColor = app.callingApp.DEFAULT_COLOR;
%                 end
%             end
%         end
        function createMainObj(app)
            app.tableAutoCalibPath = app.tableTextArea.Value{1};

            app = readTableInfo(app);
            app = readTableResultSample(app);
            app = readTablePin(app);
            app = readTablePout(app);

            app.opticLineNumber = app.LineDropDown.Value;
            app.PINname = ['PIN' app.opticLineNumber(2) '_FILT_CAL'];
            %disp(app.PIN)
            app.POUTname = ['POUT' app.opticLineNumber(2) '_FILT_CAL'];

            %app.mode = app.SelectmodeDropDown.Value;
            app.nameVDout_1 = getNameVD1(app,'P_out');
            app.nameVDout_2 = getNameVD2(app,'P_out');
            varNames = app.tablePout.Properties.VariableNames;
            [logic, index] = ismember('ADCForNameFPGA1',varNames);
            varNames(1,index) = app.nameVDout_1;
            app.tablePout.Properties.VariableNames = varNames;
            varNames = app.tablePout.Properties.VariableNames;
            [logic, index] = ismember('ADCForNameFPGA2',varNames);
            varNames(1,index) = app.nameVDout_2;
            app.tablePout.Properties.VariableNames = varNames;
            app.nameVDout_1 = app.nameVDout_1{1};
            app.nameVDout_2 = app.nameVDout_2{1};

            app.nameVDin_1 = getNameVD1(app,'P_in');
            app.nameVDin_1 = app.nameVDin_1{1};
            app.nameVDin_2 = getNameVD2(app,'P_in');
            app.nameVDin_2 = app.nameVDin_2{1};
            varNames = app.tablePin.Properties.VariableNames;
            [logic, index] = ismember('ADCForNameFPGA1',varNames);
            varNames(1,index) = {app.nameVDin_1};
            app.tablePin.Properties.VariableNames = varNames;
            varNames = app.tablePin.Properties.VariableNames;
            [logic, index] = ismember('ADCForNameFPGA2',varNames);
            varNames(1,index) = {app.nameVDin_2};
            app.tablePin.Properties.VariableNames = varNames;
            
            app.FPGA = setTransceiversEn(app.FPGA, '0000');
        end
        function deleteMainObj(app)

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.callingApp = MainApp;
            app.folder = MainApp.folder;
            loadFromMemFileToApp(app);
            try
                app.FPGA = MainApp.AddDevice_FPGA.mainObj;
                app.ATTENniMyDaq = MainApp.AddDevice_ATTENniMyDaq.mainObj;
                app.SWITCHosaEdfa = MainApp.AddDevice_SWITCHosaEdfa.mainObj;
                if isvalid(MainApp.AddDevice_OPMrubin)
                    if ~isempty(MainApp.AddDevice_OPMrubin)
                        app.Pin = mainApp.struct_OPMrubin.Pin;
                        app.Pout = mainApp.struct_OPMrubin.Pout;
                    end
                end
            catch ME
                report = getReport(ME);
                uialert(app.callingApp.GreatUniterUIFigure,report,'Error Message');%,'Interpreter','html');
            end
        end

        % Callback function
        function readADCP_inforoffsetButtonPushed(app, event)
%             app.FPGA=setDiodesEn(app.FPGA, '0000');
%             disableWarningLaser(app.callingApp);
%             app.Pin = requestPower(app.Pin);
%             if app.Pin.powerdBm(end) > -50
%                 warning('мощность с рубина > -50 дБм');
%             end
%             nameVD_1 = getNameVD1(app,'P_in');
%             varNames = app.tablePin.Properties.VariableNames;
%             [logic, index] = ismember('ADCForNameFPGA1',varNames);
%             varNames(1,index) = nameVD_1;
%             nameVD_1 = nameVD_1{1};
%             app.tablePin.Properties.VariableNames = varNames;
%             app.FPGA = readData(app.FPGA,nameVD_1);
%             app.tablePin{1,nameVD_1} = -1*str2double(app.FPGA.lastRead);
%             app.tableResultSample{nameVD_1,'OFFSET_'} = str2double(app.FPGA.lastRead);
%             str = ['OFFSET_' nameVD_1 ' = ' app.FPGA.lastRead];
%             addStrOutput(app.callingApp,str);
%             app.FPGA = readData(app.FPGA,'PIN1_FILT_CAL');
%             app.tablePin{1,'PIN1_FILT_CAL'} = str2double(app.FPGA.lastRead);
%             app.FPGA = readData(app.FPGA,'PIN2_FILT_CAL');
%             app.tablePin{1,'PIN2_FILT_CAL'} = str2double(app.FPGA.lastRead);
%             nameVD_2 = getNameVD2(app,'P_in');
%             varNames = app.tablePin.Properties.VariableNames;
%             [logic, index] = ismember('ADCForNameFPGA2',varNames);
%             varNames(1,index) = nameVD_2;
%             nameVD_2 = nameVD_2{1};
%             app.tablePin.Properties.VariableNames = varNames;
%             app.FPGA = readData(app.FPGA,nameVD_2);
%             app.tablePin{1,nameVD_2} = -1*str2double(app.FPGA.lastRead);
%             app.tableResultSample{nameVD_2,'OFFSET_'} = str2double(app.FPGA.lastRead);
%             str = ['OFFSET_' nameVD_2 ' = ' app.FPGA.lastRead];
%             addStrOutput(app.callingApp,str);
%             app.calibrateP_inButton.Enable = 'on';
        end

        % Button pushed function: calibrateP_inButton
        function calibrateP_inButtonPushed(app, event)
            app.FPGA = setDiodesEn(app.FPGA, '0000');
            disableWarningLaser(app.callingApp);
            app.FPGA = setTransceiversEn(app.FPGA, '0000');
            app.SWITCHosaEdfa = switchSignalTo(app.SWITCHosaEdfa,'EDFA');
            app.ATTENniMyDaq = setMaxAttenuation(app.ATTENniMyDaq);
            for i = 1:app.rowNumberPin
                if i ~= 1 && app.tablePin{i,'InputPowerThDBm'} == app.tablePin{i-1,'InputPowerThDBm'}
                    n = 1;
                else
                    n = 3;
                end
                for j = 1:n
                    app.Pin = requestPower(app.Pin);
                    powerReal = app.Pin.powerdBm(end);
                    powerTheor = app.tablePin{i,'InputPowerThDBm'};
                    currentAttenuation = app.ATTENniMyDaq.lastAttenuation;
                    attenuation = powerReal - powerTheor + currentAttenuation;
                    app.tablePin{i,'AttenuationForInputPowerDBm'} = attenuation;
                    app.ATTENniMyDaq = setAttenuation(app.ATTENniMyDaq,attenuation);
                    pause(1)
                end
                addStrOutput(app.callingApp,['Выставлена аттенюация ' num2strForPrint(attenuation) ' дБм']);

                pause(app.tablePin{i,'PauseAfterAttenuationS'});

                app.Pin = requestPower(app.Pin);
                powerReal = app.Pin.powerdBm(end);

                app.tablePin{i,'InputPowerRealDBm'} = powerReal;
                

                powerRealmW = dbm2w(powerReal,'m');
                app.tablePin{i,'InputPowerRealMW'} = powerRealmW;

                app.FPGA = readData(app.FPGA,app.nameVDin_1);
                app.tablePin{i,app.nameVDin_1} = str2double(app.FPGA.lastRead);

                app.FPGA = readData(app.FPGA,app.nameVDin_2);
                app.tablePin{i,app.nameVDin_2} = str2double(app.FPGA.lastRead);

                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,'SEL_PH_Pin','0');
                pause(0.5)
                app.FPGA = readData(app.FPGA,app.PINname);
                app.tablePin{i,'PIN_1'} = str2double(app.FPGA.lastRead);

                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,'SEL_PH_Pin','3');
                pause(0.5)
                app.FPGA = readData(app.FPGA,app.PINname);
                app.tablePin{i,'PIN_2'} = str2double(app.FPGA.lastRead);
            end
            addStrOutput(app.callingApp,'Входные ФП проверены');
            if app.InDropDown.Value == "test coeffs"
                tableVD = app.tablePin;
                lossdB = app.Loss_inEditField.Value;
                k_mkWadc = app.Coeff_inEditField.Value;
                coeff = addToCalibVDFUN(app.folder,'in','test',tableVD,lossdB,k_mkWadc,app.nameVDin_1);
                app.tableResultSample{app.nameVDin_1,'COEFF_'} = coeff;
                coeffName = ['COEFF_Pin' app.opticLineNumber(end) '_1'];
                if app.InwritetoEEPROMCheckBox.Value
                    app.FPGA = writeAndReadData(app.FPGA,app.FPGA.EEPROM_MEM,coeffName,num2str(coeff));
                end
                if app.InwritetoFLASHCheckBox.Value
                    app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,coeffName,num2str(coeff));
                end
                coeff = addToCalibVDFUN(app.folder,'in','test',tableVD,lossdB,k_mkWadc,app.nameVDin_2);
                app.tableResultSample{app.nameVDin_2,'COEFF_'} = coeff;
                coeffName = ['COEFF_Pin' app.opticLineNumber(end) '_2'];
                if app.InwritetoEEPROMCheckBox.Value
                    app.FPGA = writeAndReadData(app.FPGA,app.FPGA.EEPROM_MEM,coeffName,num2str(coeff));
                end
                if app.InwritetoFLASHCheckBox.Value
                    app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,coeffName,num2str(coeff));
                end
                addStrOutput(app.callingApp,'Входные ФП обработаны');
            elseif app.InDropDown.Value == "get coeffs"
                tableVD = app.tablePin;
                lossdB = app.Loss_inEditField.Value;
                k_mkWadc = app.Coeff_inEditField.Value;
                coeff = addToCalibVDFUN(app.folder,'in','get',tableVD,lossdB,k_mkWadc,app.nameVDin_1);
                app.tableResultSample{app.nameVDin_1,'COEFF_'} = coeff;
                coeffName = ['COEFF_Pin' app.opticLineNumber(end) '_1'];
                if app.InwritetoEEPROMCheckBox.Value
                    app.FPGA = writeAndReadData(app.FPGA,app.FPGA.EEPROM_MEM,coeffName,num2str(coeff));
                end
                if app.InwritetoFLASHCheckBox.Value
                    app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,coeffName,num2str(coeff));

                end
                coeff = addToCalibVDFUN(app.folder,'in','get',tableVD,lossdB,k_mkWadc,app.nameVDin_2);
                app.tableResultSample{app.nameVDin_2,'COEFF_'} = coeff;
                coeffName = ['COEFF_Pin' app.opticLineNumber(end) '_2'];
                if app.InwritetoEEPROMCheckBox.Value
                    app.FPGA = writeAndReadData(app.FPGA,app.FPGA.EEPROM_MEM,coeffName,num2str(coeff));
                end
                if app.InwritetoFLASHCheckBox.Value
                    app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,coeffName,num2str(coeff));
                end
                addStrOutput(app.callingApp,'Входные ФП обработаны');
            end
            if app.InsavetableCheckBox.Value
                saveTablePin(app);
                addStrOutput(app.callingApp,'Таблица TablePin сохранена');
            end
        end

        % Button pushed function: calibrateP_outButton
        function calibrateP_outButtonPushed(app, event)
            app.FPGA = setDiodesEn(app.FPGA, '0000');
            disableWarningLaser(app.callingApp);
            app.FPGA = setTransceiversEn(app.FPGA, '0000');
            app.SWITCHosaEdfa = switchSignalTo(app.SWITCHosaEdfa,'EDFA');
            app.ATTENniMyDaq = setMaxAttenuation(app.ATTENniMyDaq);
            for i = 1:app.rowNumberPout
                if i ~= 1 && app.tablePout{i,'InputPowerThDBm'} == app.tablePout{i-1,'InputPowerThDBm'}
                    n = 1;
                else
                    n = 3;
                end
                for j = 1:n
                    app.Pin = requestPower(app.Pin);
                    powerReal = app.Pin.powerdBm(end);
                    powerTheor = app.tablePout{i,'InputPowerThDBm'};
                    currentAttenuation = app.ATTENniMyDaq.lastAttenuation;
                    attenuation = powerReal - powerTheor + currentAttenuation;
                    app.tablePout{i,'AttenuationForInputPowerDBm'} = attenuation;
                    app.ATTENniMyDaq = setAttenuation(app.ATTENniMyDaq,attenuation);
                    pause(1)
                end
                addStrOutput(app.callingApp,['Выставлена аттенюация ' num2strForPrint(attenuation) ' дБм']);
                pause(app.tablePout{i,'PauseAfterAttenuationS'});

                app.Pin = requestPower(app.Pin);
                powerReal = app.Pin.powerdBm(end);

                app.tablePout{i,'InputPowerRealDBm'} = powerReal;

                powerRealmW = dbm2w(powerReal,'m');
                app.tablePout{i,'InputPowerRealMW'} = powerRealmW;

                enableWarningLaser(app.callingApp);
                app.FPGA=setDiodesEn(app.FPGA, '1111');
                %powerValue = app.tablePout{i,'OutputPowerTh'};

                %%%% ЧАСТЬ РАБОТЫ С ТАБЛИЦЕЙ %%%%
                varNames = app.tablePout.Properties.VariableNames;
                name1 = varNames{1,6};
                name2 = varNames{1,7};

                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,'MODE','0');

                wvalue1 = num2str(app.tablePout{i,name1});
                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,name1,wvalue1);
                addStrOutput(app.callingApp,['Записано ' name1 ' = ' wvalue1]);

                wvalue2 = num2str(app.tablePout{i,name2});
                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,name2,wvalue2);
                addStrOutput(app.callingApp,['Записано ' name2 ' = ' wvalue2]);

                pauseSec = app.tablePout{i,'PauseAfterSetOutputPowerS'};
                addStrOutput(app.callingApp,['Ожидание ' num2strForPrint(pauseSec) ' с']);
                pause(pauseSec);
                %сделать аналогично тесту
                %setOutputPowerEDFA(app.FPGA,app.opticLineNumber,powerValue);

                pause(app.tablePout{i,'PauseAfterSetOutputPowerS'});

                app.Pout = requestPower(app.Pout);
                powerReal = app.Pout.powerdBm(end);
                powerReal = powerReal - app.PEditField.Value;
                app.tablePout{i,'OutputPowerRealDBm'} = powerReal;

                powerRealmW = dbm2w(powerReal,'m');
                app.tablePout{i,'OutputPowerRealMW'} = powerRealmW;

                app.FPGA = readData(app.FPGA,app.nameVDout_1);
                app.tablePout{i,app.nameVDout_1} = str2double(app.FPGA.lastRead);

                app.FPGA = readData(app.FPGA,app.nameVDout_2);
                app.tablePout{i,app.nameVDout_2} = str2double(app.FPGA.lastRead);

                nameVDoffset_1 = ['OFFSET_' app.nameVDout_1];
                app.FPGA = readData(app.FPGA,nameVDoffset_1);
                oldOffset_1 = app.FPGA.lastRead;

                nameVDoffset_2 = ['OFFSET_' app.nameVDout_2];
                app.FPGA = readData(app.FPGA,nameVDoffset_2);
                oldOffset_2 = app.FPGA.lastRead;

                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,nameVDoffset_2,'-100000');
                pause(0.5);
                app.FPGA = readData(app.FPGA,app.POUTname);
                app.tablePout{i,'POUT_1'} = str2double(app.FPGA.lastRead);
                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,nameVDoffset_2,oldOffset_2);

                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,nameVDoffset_1,'-100000');
                pause(0.5);
                app.FPGA = readData(app.FPGA,app.POUTname);
                app.tablePout{i,'POUT_2'} = str2double(app.FPGA.lastRead);
                app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,nameVDoffset_1,oldOffset_1);
            end
            disableWarningLaser(app.callingApp);
            app.FPGA=setDiodesEn(app.FPGA, '0000');
            wvalue1 = '0';
            app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,name1,wvalue1);
            addStrOutput(app.callingApp,['Записано ' name1 ' = ' wvalue1]);
            wvalue2 = '0';
            app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,name2,wvalue2);
            addStrOutput(app.callingApp,['Записано ' name2 ' = ' wvalue2]);
            addStrOutput(app.callingApp,'Выходные ФП проверены');
            if app.InDropDown.Value == "test coeffs"
                tableVD = app.tablePout;
                lossdB = app.Loss_outEditField.Value;
                k_mkWadc = app.Coeff_outEditField.Value;
                coeff = addToCalibVDFUN(app.folder,'out','test',tableVD,lossdB,k_mkWadc,app.nameVDout_1);
                app.tableResultSample{app.nameVDout_1,'COEFF_'} = coeff;
                if app.OutwritetoEEPROMCheckBox.Value
                    coeffName = ['COEFF_Pout' app.opticLineNumber(end) '_1'];
                    app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,coeffName,num2str(coeff));
                    app.FPGA = writeAndReadData(app.FPGA,app.FPGA.EEPROM_MEM,coeffName,num2str(coeff));
                end
                coeff = addToCalibVDFUN(app.folder,'out','test',tableVD,lossdB,k_mkWadc,app.nameVDout_2);
                app.tableResultSample{app.nameVDout_2,'COEFF_'} = coeff;
                if app.OutwritetoEEPROMCheckBox.Value
                    coeffName = ['COEFF_Pout' app.opticLineNumber(end) '_2'];
                    app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,coeffName,num2str(coeff));
                    app.FPGA = writeAndReadData(app.FPGA,app.FPGA.EEPROM_MEM,coeffName,num2str(coeff));
                end
                addStrOutput(app.callingApp,'Выходные ФП обработаны');
            elseif app.InDropDown.Value == "get coeffs"
                tableVD = app.tablePout;
                lossdB = app.Loss_outEditField.Value;
                k_mkWadc = app.Coeff_outEditField.Value;
                coeff = addToCalibVDFUN(app.folder,'out','get',tableVD,lossdB,k_mkWadc,app.nameVDout_1);
                app.tableResultSample{app.nameVDout_1,'COEFF_'} = coeff;
                if app.OutwritetoEEPROMCheckBox.Value
                    coeffName = ['COEFF_Pout' app.opticLineNumber(end) '_1'];
                    app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,coeffName,num2str(coeff));
                    app.FPGA = writeAndReadData(app.FPGA,app.FPGA.EEPROM_MEM,coeffName,num2str(coeff));
                end
                coeff = addToCalibVDFUN(app.folder,'out','get',tableVD,lossdB,k_mkWadc,app.nameVDout_2);
                app.tableResultSample{app.nameVDout_2,'COEFF_'} = coeff;
                if app.OutwritetoEEPROMCheckBox.Value
                    coeffName = ['COEFF_Pout' app.opticLineNumber(end) '_2'];
                    app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,coeffName,num2str(coeff));
                    app.FPGA = writeAndReadData(app.FPGA,app.FPGA.EEPROM_MEM,coeffName,num2str(coeff));
                end
                addStrOutput(app.callingApp,'Выходные ФП обработаны');
            end
            if app.OutsavetableCheckBox.Value
                saveTablePout(app);
                addStrOutput(app.callingApp,'Таблица TablePout сохранена');
            end
        end

        % Button pushed function: getoffsetsButton
        function getoffsetsButtonPushed(app, event)
            app.FPGA = setTransceiversEn(app.FPGA, '0000');
            app.SWITCHosaEdfa = switchSignalTo(app.SWITCHosaEdfa,'OSA');
            
            app.N = app.OffsetTimesEditField.Value;%количество снятий для вычисления среднего
            
            app = getMeanForOffset(app,app.nameVDin_1);
            app = getMeanForOffset(app,app.nameVDin_2);
            app = getMeanForOffset(app,app.nameVDout_1);
            app = getMeanForOffset(app,app.nameVDout_2);

            if app.OffsetsavetableCheckBox.Value
                saveTableResultSample(app);
                addStrOutput(app.callingApp,'Таблица TableResultSample сохранена');
            end
        end

        % Button pushed function: writeinitialcoeffstoFPGAButton
        function writeinitialcoeffstoFPGAButtonPushed(app, event)
            app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,'COEFF_Pin1_1','65535');
            app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,'COEFF_Pin1_2','65535');
            app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,'COEFF_Pin2_1','65535');
            app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,'COEFF_Pin2_2','65535');
            app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,'COEFF_Pout1_1','65535');
            app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,'COEFF_Pout1_2','65535');
            app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,'COEFF_Pout2_1','65535');
            app.FPGA = writeAndReadData(app.FPGA,app.FPGA.FLASH_MEM,'COEFF_Pout2_2','65535');
            addStrOutput(app.callingApp,'Нулевые коэффициенты записаны');
        end

        % Button pushed function: readcoefficientsfromFPGAButton
        function readcoefficientsfromFPGAButtonPushed(app, event)
            addStrOutput(app.callingApp,'Коэффициенты записаны следующие:');
            app.FPGA = readData(app.FPGA,app.FPGA.FLASH_MEM,'COEFF_Pin1_1');
            addStrOutput(app.callingApp,app.FPGA.lastRead);
            app.FPGA = readData(app.FPGA,app.FPGA.FLASH_MEM,'COEFF_Pin1_2');
            addStrOutput(app.callingApp,app.FPGA.lastRead);
            app.FPGA = readData(app.FPGA,app.FPGA.FLASH_MEM,'COEFF_Pin2_1');
            addStrOutput(app.callingApp,app.FPGA.lastRead);
            app.FPGA = readData(app.FPGA,app.FPGA.FLASH_MEM,'COEFF_Pin2_2');
            addStrOutput(app.callingApp,app.FPGA.lastRead);
            app.FPGA = readData(app.FPGA,app.FPGA.FLASH_MEM,'COEFF_Pout1_1');
            addStrOutput(app.callingApp,app.FPGA.lastRead);
            app.FPGA = readData(app.FPGA,app.FPGA.FLASH_MEM,'COEFF_Pout1_2');
            addStrOutput(app.callingApp,app.FPGA.lastRead);
            app.FPGA = readData(app.FPGA,app.FPGA.FLASH_MEM,'COEFF_Pout2_1');
            addStrOutput(app.callingApp,app.FPGA.lastRead);
            app.FPGA = readData(app.FPGA,app.FPGA.FLASH_MEM,'COEFF_Pout2_2');
            addStrOutput(app.callingApp,app.FPGA.lastRead);
        end

        % Value changed function: ApplyButton
        function ApplyButtonValueChanged(app, event)
            value = app.ApplyButton.Value;
            switch value
                case 0
                    app.ApplyButton.Text = 'Apply';
                    app.SettingsPanel.Enable = 'on';
                    app.InCoeffsPanel.Enable = 'off';
                    app.OutCoeffsPanel.Enable = 'off';
                    app.OffsetsPanel.Enable = 'off';
                    deleteMainObj(app);
                case 1
                    app.ApplyButton.Text = 'Restart';
                    app.SettingsPanel.Enable = 'off';
                    app.InCoeffsPanel.Enable = 'on';
                    app.OutCoeffsPanel.Enable = 'on';
                    app.OffsetsPanel.Enable = 'on';
                    createMainObj(app);
            end
        end

        % Button pushed function: ZoomInOneButton
        function ZoomInOneButtonPushed(app, event)
            imageview(app.CircuitImage.ImageSource);
        end

        % Button pushed function: downloadTableButton
        function downloadTableButtonPushed(app, event)
            output = selectTable(app.tableTextArea.Value{1});
            app.tableTextArea.Value = output;
        end

        % Close request function: CalibrateEDFAUIFigure
        function CalibrateEDFAUIFigureCloseRequest(app, event)
            fig = app.CalibrateEDFAUIFigure;
            confirmText = "Do you want to save input data before exit?";
            answer = uiconfirm(fig,confirmText,"Confirm save", ...
                "Options",["Save" "Exit" "Cancel"],...
                'DefaultOption',"Save",'CancelOption',"Cancel",...
                'Icon','gu_help.png');
            if answer == "Save"
                saveFromAppToMemFile(app);
            end
            if answer == "Save" || answer == "Exit"
                delete(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CalibrateEDFAUIFigure = uifigure('Visible', 'off');
            app.CalibrateEDFAUIFigure.Name = 'Calibrate EDFA';
            app.CalibrateEDFAUIFigure.Icon = 'gu_action.png';
            app.CalibrateEDFAUIFigure.CloseRequestFcn = createCallbackFcn(app, @CalibrateEDFAUIFigureCloseRequest, true);
            app.HeaderLabel = uilabel(app.CalibrateEDFAUIFigure);
            app.HeaderLabel.Text = 'Сalibrate EDFA';
            app.SettingsPanel = uipanel(app.CalibrateEDFAUIFigure);
            app.Label_2 = uilabel(app.SettingsPanel);
            app.Label_2.Text = 'Select calibration table';
            app.tableTextArea = uitextarea(app.SettingsPanel);
            app.tableTextArea.Value = {'autoCalibration.xlsx'};
            app.Label = uilabel(app.SettingsPanel);
            app.Label.Text = 'Select optical line';
            app.LineDropDown = uidropdown(app.SettingsPanel);
            app.LineDropDown.Items = {'L1', 'L2'};
            app.LineDropDown.Value = 'L1';
            app.CircuitImage = uiimage(app.SettingsPanel);
            app.CircuitImage.ImageSource = 'EDFA_Calibrate.jpg';
            app.AssembleopticalcircuitentervaluePPPinPoutLabel = uilabel(app.SettingsPanel);
            app.AssembleopticalcircuitentervaluePPPinPoutLabel.Text = {'Assemble optical circuit, enter value ΔP'; 'ΔP = Pin - Pout'};
            app.deltaPrubinLabel = uilabel(app.SettingsPanel);
            app.deltaPrubinLabel.Text = 'ΔP =';
            app.PEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.PEditField.Tooltip = {'P_2 - P_1'};
            app.PEditField.Value = 0.13;
            app.Label_3 = uilabel(app.SettingsPanel);
            app.Label_3.Text = 'Custom text for filenames';
            app.UserTextEditField = uieditfield(app.SettingsPanel, 'text');
            app.UserTextEditField.Value = 'myFirstCalibration';
            app.ZoomInOneButton = uibutton(app.SettingsPanel, 'push');
            app.ZoomInOneButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomInOneButtonPushed, true);
            app.ZoomInOneButton.Icon = 'gu_zoomin.png';
            app.ZoomInOneButton.Tooltip = {'Zoom in'};
            app.ZoomInOneButton.Text = '';
            app.downloadTableButton = uibutton(app.SettingsPanel, 'push');
            app.downloadTableButton.ButtonPushedFcn = createCallbackFcn(app, @downloadTableButtonPushed, true);
            app.downloadTableButton.Icon = 'gu_microsoft-excel.png';
            app.downloadTableButton.Text = '';
            app.dBLabel = uilabel(app.SettingsPanel);
            app.dBLabel.Text = 'dB';
            app.CheckCoeffsLabel = uilabel(app.SettingsPanel);
            app.CheckCoeffsLabel.Text = 'Before starting make sure there are initial coefficients in FPGA memory';
            app.readcoefficientsfromFPGAButton = uibutton(app.SettingsPanel, 'push');
            app.readcoefficientsfromFPGAButton.ButtonPushedFcn = createCallbackFcn(app, @readcoefficientsfromFPGAButtonPushed, true);
            app.readcoefficientsfromFPGAButton.Tooltip = {'Читает коэффициенты с ПЛИС и выводит в '};
            app.readcoefficientsfromFPGAButton.Text = 'read coefficients from FPGA';
            app.writeinitialcoeffstoFPGAButton = uibutton(app.SettingsPanel, 'push');
            app.writeinitialcoeffstoFPGAButton.ButtonPushedFcn = createCallbackFcn(app, @writeinitialcoeffstoFPGAButtonPushed, true);
            app.writeinitialcoeffstoFPGAButton.Text = 'write initial coeffs to FPGA';
            app.Label_13 = uilabel(app.SettingsPanel);
            app.Label_13.Enable = 'off';
            app.Label_13.Text = 'Select mode';
            app.SelectmodeDropDown = uidropdown(app.SettingsPanel);
            app.SelectmodeDropDown.Items = {'0', '2'};
            app.SelectmodeDropDown.Enable = 'off';
            app.SelectmodeDropDown.Value = '0';
            app.InCoeffsPanel = uipanel(app.CalibrateEDFAUIFigure);
            app.InCoeffsPanel.Enable = 'off';
            app.Loss_inEditFieldLabel = uilabel(app.InCoeffsPanel);
            app.Loss_inEditFieldLabel.Text = 'Loss_in';
            app.Loss_inEditField = uieditfield(app.InCoeffsPanel, 'numeric');
            app.Loss_inEditField.Value = 19.26;
            app.Coeff_inEditFieldLabel = uilabel(app.InCoeffsPanel);
            app.Coeff_inEditFieldLabel.Text = 'Coeff_in';
            app.Coeff_inEditField = uieditfield(app.InCoeffsPanel, 'numeric');
            app.Coeff_inEditField.Value = 0.00024;
            app.InwritetoEEPROMCheckBox = uicheckbox(app.InCoeffsPanel);
            app.InwritetoEEPROMCheckBox.Text = 'write to EEPROM';
            app.InwritetoFLASHCheckBox = uicheckbox(app.InCoeffsPanel);
            app.InwritetoFLASHCheckBox.Text = 'write to FLASH';
            app.InsavetableCheckBox = uicheckbox(app.InCoeffsPanel);
            app.InsavetableCheckBox.Text = 'save table';
            app.InsavetableCheckBox.Value = true;
            app.dBLabel_2 = uilabel(app.InCoeffsPanel);
            app.dBLabel_2.Text = 'dB';
            app.mkWADCLabel = uilabel(app.InCoeffsPanel);
            app.mkWADCLabel.Text = 'mkW/ADC';
            app.calibrateP_inButton = uibutton(app.InCoeffsPanel, 'push');
            app.calibrateP_inButton.ButtonPushedFcn = createCallbackFcn(app, @calibrateP_inButtonPushed, true);
            app.calibrateP_inButton.Text = 'calibrate P_in';
            app.InDropDown = uidropdown(app.InCoeffsPanel);
            app.InDropDown.Items = {'test coeffs', 'get coeffs'};
            app.InDropDown.Value = 'test coeffs';
            app.OutCoeffsPanel = uipanel(app.CalibrateEDFAUIFigure);
            app.OutCoeffsPanel.Enable = 'off';
            app.Loss_outEditFieldLabel = uilabel(app.OutCoeffsPanel);
            app.Loss_outEditFieldLabel.Text = 'Loss_out';
            app.Loss_outEditField = uieditfield(app.OutCoeffsPanel, 'numeric');
            app.Loss_outEditField.Value = 17.66;
            app.Coeff_outEditFieldLabel = uilabel(app.OutCoeffsPanel);
            app.Coeff_outEditFieldLabel.Text = 'Coeff_out';
            app.Coeff_outEditField = uieditfield(app.OutCoeffsPanel, 'numeric');
            app.Coeff_outEditField.Value = 0.00457;
            app.OutwritetoEEPROMCheckBox = uicheckbox(app.OutCoeffsPanel);
            app.OutwritetoEEPROMCheckBox.Text = 'write to EEPROM';
            app.OutwritetoFLASHCheckBox = uicheckbox(app.OutCoeffsPanel);
            app.OutwritetoFLASHCheckBox.Text = 'write to FLASH';
            app.OutsavetableCheckBox = uicheckbox(app.OutCoeffsPanel);
            app.OutsavetableCheckBox.Text = 'save table';
            app.OutsavetableCheckBox.Value = true;
            app.dBLabel_3 = uilabel(app.OutCoeffsPanel);
            app.dBLabel_3.Text = 'dB';
            app.mkWADCLabel_2 = uilabel(app.OutCoeffsPanel);
            app.mkWADCLabel_2.Text = 'mkW/ADC';
            app.calibrateP_outButton = uibutton(app.OutCoeffsPanel, 'push');
            app.calibrateP_outButton.ButtonPushedFcn = createCallbackFcn(app, @calibrateP_outButtonPushed, true);
            app.calibrateP_outButton.Text = 'calibrate P_out';
            app.OutDropDown = uidropdown(app.OutCoeffsPanel);
            app.OutDropDown.Items = {'test coeffs', 'get coeffs'};
            app.OutDropDown.Value = 'test coeffs';
            app.OffsetsPanel = uipanel(app.CalibrateEDFAUIFigure);
            app.TimestogetvaluesfromVDLabel = uilabel(app.OffsetsPanel);
            app.TimestogetvaluesfromVDLabel.Text = 'Times to get values from VD';
            app.OffsetTimesEditField = uieditfield(app.OffsetsPanel, 'numeric');
            app.OffsetTimesEditField.Value = 40;
            app.OffsetwritetoFLASHCheckBox = uicheckbox(app.OffsetsPanel);
            app.OffsetwritetoFLASHCheckBox.Text = 'write to FLASH';
            app.OffsetwritetoFLASHCheckBox.Value = true;
            app.OffsetwritetoEEPROMCheckBox = uicheckbox(app.OffsetsPanel);
            app.OffsetwritetoEEPROMCheckBox.Text = 'write to EEPROM';
            app.OffsetwritetoEEPROMCheckBox.Value = true;
            app.OffsetsavetableCheckBox = uicheckbox(app.OffsetsPanel);
            app.OffsetsavetableCheckBox.Text = 'save table';
            app.OffsetsavetableCheckBox.Value = true;
            app.getoffsetsButton = uibutton(app.OffsetsPanel, 'push');
            app.getoffsetsButton.ButtonPushedFcn = createCallbackFcn(app, @getoffsetsButtonPushed, true);
            app.getoffsetsButton.Text = 'get offsets';
            app.ApplyButton = uibutton(app.CalibrateEDFAUIFigure, 'state');
            app.ApplyButton.ValueChangedFcn = createCallbackFcn(app, @ApplyButtonValueChanged, true);
            app.ApplyButton.Text = 'Apply';
            % Show the figure after all components are created
            app.CalibrateEDFAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appCalibrateEDFA(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CalibrateEDFAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CalibrateEDFAUIFigure)
        end
    end
end