classdef appSWITCH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        RefreshButton           matlab.ui.control.Button
        Label_2                 matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        SendButton              matlab.ui.control.Button
        EditField               matlab.ui.control.EditField
        Label_3                 matlab.ui.control.Label
        SwitchOSAEDFA           matlab.ui.control.Switch
        ConfirmButton           matlab.ui.control.Button
        ButtonGroup             matlab.ui.container.ButtonGroup
        OSAButton               matlab.ui.control.RadioButton
        EDFAButton              matlab.ui.control.RadioButton
        HeaderLabel             matlab.ui.control.Label
        RubinPanel              matlab.ui.container.Panel
        Switch                  matlab.ui.control.Switch
        COMportsEditField       matlab.ui.control.EditField
        COMportsEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        switchOsaEdfa % Description
        callingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.callingApp = MainApp;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == "Connect"
                app.switchOsaEdfa = [];
                app.switchOsaEdfa = SwitchOsaEdfa(app);
                output = 'SWITCH connected';
            elseif value == "Disconnect"
                if ~isequal(class(app.switchOsaEdfa),'double')
                    app.switchOsaEdfa = deleteVirtualObject(app.switchOsaEdfa);
                    output = 'SWITCH disconnected';
                end
            end
            printOutput(app.callingApp,output);
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            requestCommand = app.EditField.Value;
            sendCommand(app.switchOsaEdfa,requestCommand);
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            if app.ButtonGroup.SelectedObject == app.EDFAButton
                app.switchOsaEdfa = switchSignalEDFA(app.switchOsaEdfa);
                app.SwitchOSAEDFA.Value = "EDFA";
            elseif app.ButtonGroup.SelectedObject == app.OSAButton
                app.switchOsaEdfa = switchSignalOSA(app.switchOsaEdfa);
                app.SwitchOSAEDFA.Value = "OSA";
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonButtonPushed(app, event)
            app.switchOsaEdfa = requestSwitchState(app.switchOsaEdfa);
            app.SwitchOSAEDFA.Value = app.switchOsaEdfa.switchState;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RubinPanel = uipanel(app.UIFigure);
            app.COMportsEditFieldLabel = uilabel(app.RubinPanel);
            app.COMportsEditFieldLabel.Text = 'COM-ports';
            app.COMportsEditField = uieditfield(app.RubinPanel, 'text');
            app.COMportsEditField.Value = 'COM3';
            app.Switch = uiswitch(app.RubinPanel, 'slider');
            app.Switch.Items = {'Disconnect', 'Connect'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Disconnect';
            app.HeaderLabel = uilabel(app.UIFigure);
            app.HeaderLabel.Text = 'SWITCH (OSA/EDFA)';
            app.Panel = uipanel(app.UIFigure);
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.EDFAButton = uiradiobutton(app.ButtonGroup);
            app.EDFAButton.Text = 'EDFA';
            app.EDFAButton.Value = true;
            app.OSAButton = uiradiobutton(app.ButtonGroup);
            app.OSAButton.Text = 'OSA';
            app.ConfirmButton = uibutton(app.Panel, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Icon = 'icon_confirm.png';
            app.ConfirmButton.Tooltip = {'confirm'};
            app.ConfirmButton.Text = '';
            app.SwitchOSAEDFA = uiswitch(app.Panel, 'slider');
            app.SwitchOSAEDFA.Items = {'OSA', 'EDFA'};
            app.SwitchOSAEDFA.Enable = 'off';
            app.SwitchOSAEDFA.Value = 'EDFA';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = 'Отправить свою команду';
            app.EditField = uieditfield(app.Panel, 'text');
            app.EditField.Tooltip = {'Команды:'; '1. Номер ключа (001,002 или 003)'; '2. Точка с запятой (;)'; '3. Состояние ключа (0 или 1)'; '4. Точка с запятой (;)'; 'Например, 001;1; переключает первый ключ, визуально можно будет увидеть, что первый диод загорелся.'};
            app.EditField.Value = '003;1;';
            app.SendButton = uibutton(app.Panel, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'send';
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'Выбере положение ключей и нажмите кнопку';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = 'Текущее положение ключей';
            app.RefreshButton = uibutton(app.Panel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonButtonPushed, true);
            app.RefreshButton.Icon = 'icons_refresh.png';
            app.RefreshButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appSWITCH(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end