classdef appTestFPGA_LaserDiode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ApplyButton                    matlab.ui.control.Button
        PoutputEditField               matlab.ui.control.EditField
        PoutputEditFieldLabel          matlab.ui.control.Label
        CheckOffsetPanel               matlab.ui.container.Panel
        LDDN_OFFSET_HIGH_RANGEEditField  matlab.ui.control.EditField
        LDDN_OFFSET_HIGH_RANGEEditFieldLabel  matlab.ui.control.Label
        Tip1Image                      matlab.ui.control.Image
        checkButton                    matlab.ui.control.Button
        saveLabel                      matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        LoadDriverEEPROMLabel          matlab.ui.control.Label
        Switch                         matlab.ui.control.Switch
        Label_5                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        TestCircuitPanel               matlab.ui.container.Panel
        Button_2                       matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        AssembleopticalcircuitLabel    matlab.ui.control.Label
        OutputpowerEditField           matlab.ui.control.NumericEditField
        OutputpowerEditFieldLabel      matlab.ui.control.Label
        savevalueButton                matlab.ui.control.Button
        Tip1Image_2                    matlab.ui.control.Image
        IfthetestpassedpresssavebuttonLabel  matlab.ui.control.Label
        dBmLabel                       matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        LDDN_CURRENTEditField          matlab.ui.control.EditField
        LDDN_CURRENTEditFieldLabel     matlab.ui.control.Label
        writetofpgaButton              matlab.ui.control.Button
        resetButton                    matlab.ui.control.Button
        SettingsPanel                  matlab.ui.container.Panel
        WavelengthEditField            matlab.ui.control.EditField
        WavelengthEditFieldLabel       matlab.ui.control.Label
        CommentEditField               matlab.ui.control.EditField
        CommentEditField_2Label        matlab.ui.control.Label
        BoardnumberEditField           matlab.ui.control.EditField
        BoardnumberEditField_2Label    matlab.ui.control.Label
        DesignationEditField           matlab.ui.control.EditField
        DesignationEditField_2Label    matlab.ui.control.Label
        SerialnumberEditField          matlab.ui.control.EditField
        SerialnumberEditField_2Label   matlab.ui.control.Label
        InspectornameEditField         matlab.ui.control.EditField
        InspectornameEditField_2Label  matlab.ui.control.Label
        SelectComponentsTableTextArea  matlab.ui.control.TextArea
        SelecttablewithallcomponentsTextAreaLabel  matlab.ui.control.Label
        SelectSampleTableTextArea      matlab.ui.control.TextArea
        Label_2                        matlab.ui.control.Label
        downloadSampleTableButton      matlab.ui.control.Button
        downloadComponentsTableButton  matlab.ui.control.Button
        TestFPGAlaserdiodeLabel        matlab.ui.control.Label
    end

    properties (Access = private)
        callingApp
        folder
    end
    properties (Access = public)
        OPMrubin
        FPGA
        mainObj
    end
    methods (Access = public)
        function loadFromMemFileToApp(app)
            load('TheGreatUniterMemory.mat','appTestFPGA_LaserDiode');
            app.SelectComponentsTableTextArea.Value = appTestFPGA_LaserDiode.SelectComponentsTableTextArea;
            app.SelectSampleTableTextArea.Value = appTestFPGA_LaserDiode.SelectSampleTableTextArea;
            app.BoardnumberEditField.Value = appTestFPGA_LaserDiode.BoardnumberEditField;
            app.DesignationEditField.Value = appTestFPGA_LaserDiode.DesignationEditField;
            app.SerialnumberEditField.Value = appTestFPGA_LaserDiode.SerialnumberEditField;
            app.CommentEditField.Value = appTestFPGA_LaserDiode.CommentEditField;
            app.InspectornameEditField.Value = appTestFPGA_LaserDiode.InspectornameEditField;
            app.WavelengthEditField.Value = appTestFPGA_LaserDiode.WavelengthEditField;
        end
        function saveFromAppToMemFile(app)
            appTestFPGA_LaserDiode.SelectComponentsTableTextArea = app.SelectComponentsTableTextArea.Value{1};
            appTestFPGA_LaserDiode.SelectSampleTableTextArea = app.SelectSampleTableTextArea.Value{1};
            appTestFPGA_LaserDiode.BoardnumberEditField = app.BoardnumberEditField.Value;
            appTestFPGA_LaserDiode.DesignationEditField = app.DesignationEditField.Value;
            appTestFPGA_LaserDiode.SerialnumberEditField = app.SerialnumberEditField.Value;
            appTestFPGA_LaserDiode.CommentEditField = app.CommentEditField.Value;
            appTestFPGA_LaserDiode.InspectornameEditField = app.InspectornameEditField.Value;
            appTestFPGA_LaserDiode.WavelengthEditField = app.WavelengthEditField.Value;
            save('TheGreatUniterMemory.mat','appTestFPGA_LaserDiode','-append');
        end
%         function checkFPGAaddressName(app)
%             value = app.FPGAaddressDropDown.Value;
%             output = whatFRM(app.FPGA,value);
%             if output == "ERR"
%                 app.FPGAaddressDropDown.BackgroundColor = app.callingApp.errorColor;
%                 output = 'Enter correct FPGA address name';
%                 addStrOutput(app.callingApp,output);
%                 error(output);
%             else
%                 app.FPGAaddressDropDown.BackgroundColor = app.callingApp.defaultColor;
%             end
%         end
        function checkFieldsNotEmpty(app)
            fieldsArray = {...
                app.SelectComponentsTableTextArea,...
                app.SelectSampleTableTextArea,...
                app.BoardnumberEditField,...
                app.DesignationEditField,...
                app.SerialnumberEditField,...
                app.CommentEditField,...
                app.InspectornameEditField,...
                app.WavelengthEditField,...
                };
            for i = 1:length(fieldsArray)
                if iscell(fieldsArray{i}.Value)
                    value = fieldsArray{i}.Value{1};
                else
                    value = fieldsArray{i}.Value;
                end
                if isempty(value)
                    fieldsArray{i}.BackgroundColor = app.callingApp.errorColor;
                    output = 'Fields must not be empty';
                    addStrOutput(app.callingApp,output)
                    error(output);
                end
            end
        end
        function createMainObj(app)
            appStruct = struct('time',now, ...
                'folder',app.folder, ...
                'tableComponentsPath',app.SelectComponentsTableTextArea.Value{1}, ...
                'tableSamplePath',app.SelectSampleTableTextArea.Value{1}, ...
                'boardNumber',app.BoardnumberEditField.Value, ...
                'designation',app.DesignationEditField.Value, ...
                'serialNumber',app.SerialnumberEditField.Value, ...
                'comment',app.CommentEditField.Value, ...
                'inspectorName',app.InspectornameEditField.Value ...
                'operatingWavelength_nm',app.WavelengthEditField.Value, ...
                'offsetHighRange',app.LDDN_OFFSET_HIGH_RANGEEditField.Value);
            app.mainObj = FPGA_LaserDiode(appStruct);
        end
        function deleteMainObj(app)
            app.mainObj = [];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.callingApp = MainApp;
            app.folder = MainApp.folder;
            app.fpga = MainApp.calledAppFPGA.fpga;

            app.tableComponent = 'tableComponent.xlsx';
            app.tableLaserDiode = 'tableLaserDiode.xlsx';
        end

        % Button pushed function: downloadComponentsTableButton
        function downloadComponentsTableButtonPushed(app, event)
            [file,path] = uigetfile('*.xlsx','Select tableComponent file','tableComponent.xlsx');
            if isequal(file,0)
                output = "не выбрана";
            else
                output = [path file];
            end
            figure(app.UIFigure);
            app.tableComponent = output;
            app.SelectComponentsTableTextArea.Value = output;
        end

        % Button pushed function: downloadSampleTableButton
        function downloadSampleTableButtonPushed(app, event)
            [file,path] = uigetfile('*.xlsx','Select tableLaserDiode file','tableLaserDiode.xlsx');
            if isequal(file,0)
                output = "не выбрана";
            else
                output = [path file];
            end
            figure(app.UIFigure);
            app.tableLaserDiode = output;
            app.SelectSampleTableTextArea.Value = output;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            designation = app.DesignationEditField.Value;
            if isequal(designation,'PB1')
                app.LDnumber = '1';
            elseif isequal(designation,'PB2')
                app.LDnumber = '2';
            elseif isequal(designation,'PB3')
                app.LDnumber = '3';
            elseif isequal(designation,'PB4')
                app.LDnumber = '4';
            end
            app.TestCircuitPanel.Enable = 'on';
        end

        % Button pushed function: writetofpgaButton
        function writetofpgaButtonPushed(app, event)
            number = str2double(app.LDnumber);
            wvalue = num2str(2^(number-1));
            app.fpga = writeDataToFPGA(app.fpga,'LDD_EN',wvalue);
            
            enableWarningLaser(app.callingApp);
            output = 'Диоды включены';
            addStrOutput(app.callingApp,output);

            fpgaName = ['LDD' app.LDnumber '_CURRENT'];
            wvalue = app.LDDN_CURRENTEditField.Value;
            app.fpga = writeDataToFPGA(app.fpga,fpgaName,wvalue);
            
            output = ['На диод N=' app.LDnumber ' подается ток ' wvalue];
            addStrOutput(app.callingApp,output);
        end

        % Button pushed function: checkButton
        function checkButtonPushed(app, event)
            fpgaName = ['LDD' app.LDnumber '_OFFSET_HIGH_RANGE'];
            app.fpga = readDataFromFPGA(app.fpga,fpgaName);
            offset = app.fpga.lastRead;
            app.LDDN_OFFSET_HIGH_RANGEEditField.Value = offset;
            output = ['Смещение диода N=' app.LDnumber ' равно ' offset];
            addStrOutput(app.callingApp,output);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.time = now;
            laserDiode = LaserDiode(app);
            
            fpgaName = ['LDD' app.LDnumber '_CURRENT'];
            wvalue = '0';
            app.fpga = writeDataToFPGA(app.fpga,fpgaName,wvalue);
            
            app.fpga = writeDataToFPGA(app.fpga,'LDD_EN','0');
            output = 'Диоды выключены';
            addStrOutput(app.callingApp,output);
            disableWarningLaser(app.callingApp);
            
            saveTable(laserDiode);
            laserDiodeName = createFilename('LD',app.time);
            save([app.folder laserDiodeName '.mat'],'laserDiode');
            addStrOutput(app.callingApp,'Файлы сохранены');
            
            app.Button.Enable = 'off';
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            %app.callingApp.
            %delete(app);

        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            disp(value)
%             if isequal(value,'on')
%                 app.fpga = writeDataToFPGA(app.fpga,'LDD_EN','F');
%                 output = 'Диоды включены';
%                 addStrOutput(app.callingApp,output);
%                 enableWarningLaser(app.callingApp);
%             else
%                 app.fpga = writeDataToFPGA(app.fpga,'LDD_EN','0');
%                 output = 'Диоды выключены';
%                 addStrOutput(app.callingApp,output);
%                 disableWarningLaser(app.callingApp);
%             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TestFPGAlaserdiodeLabel = uilabel(app.UIFigure);
            app.TestFPGAlaserdiodeLabel.Text = 'Test FPGA laser diode';
            app.SettingsPanel = uipanel(app.UIFigure);
            app.downloadComponentsTableButton = uibutton(app.SettingsPanel, 'push');
            app.downloadComponentsTableButton.ButtonPushedFcn = createCallbackFcn(app, @downloadComponentsTableButtonPushed, true);
            app.downloadComponentsTableButton.Icon = 'gu_microsoft-excel.png';
            app.downloadComponentsTableButton.Text = '';
            app.downloadSampleTableButton = uibutton(app.SettingsPanel, 'push');
            app.downloadSampleTableButton.ButtonPushedFcn = createCallbackFcn(app, @downloadSampleTableButtonPushed, true);
            app.downloadSampleTableButton.Icon = 'gu_microsoft-excel.png';
            app.downloadSampleTableButton.Text = '';
            app.Label_2 = uilabel(app.SettingsPanel);
            app.Label_2.Text = 'Select sample table for laser diode';
            app.SelectSampleTableTextArea = uitextarea(app.SettingsPanel);
            app.SelectSampleTableTextArea.Value = {'tableLaserDiode.xlsx'};
            app.SelecttablewithallcomponentsTextAreaLabel = uilabel(app.SettingsPanel);
            app.SelecttablewithallcomponentsTextAreaLabel.Text = 'Select table with all components';
            app.SelectComponentsTableTextArea = uitextarea(app.SettingsPanel);
            app.SelectComponentsTableTextArea.Value = {'tableComponent.xlsx'};
            app.InspectornameEditField_2Label = uilabel(app.SettingsPanel);
            app.InspectornameEditField_2Label.Text = 'Inspector name';
            app.InspectornameEditField = uieditfield(app.SettingsPanel, 'text');
            app.InspectornameEditField.Value = 'Фамилия И.О.';
            app.SerialnumberEditField_2Label = uilabel(app.SettingsPanel);
            app.SerialnumberEditField_2Label.Text = 'Serial number';
            app.SerialnumberEditField = uieditfield(app.SettingsPanel, 'text');
            app.DesignationEditField_2Label = uilabel(app.SettingsPanel);
            app.DesignationEditField_2Label.Text = 'Designation';
            app.DesignationEditField = uieditfield(app.SettingsPanel, 'text');
            app.DesignationEditField.Value = 'PB1';
            app.BoardnumberEditField_2Label = uilabel(app.SettingsPanel);
            app.BoardnumberEditField_2Label.Text = 'Board number';
            app.BoardnumberEditField = uieditfield(app.SettingsPanel, 'text');
            app.BoardnumberEditField.Value = '01';
            app.CommentEditField_2Label = uilabel(app.SettingsPanel);
            app.CommentEditField_2Label.Text = 'Comment';
            app.CommentEditField = uieditfield(app.SettingsPanel, 'text');
            app.CommentEditField.Value = 'T = 22 C';
            app.WavelengthEditFieldLabel = uilabel(app.SettingsPanel);
            app.WavelengthEditFieldLabel.Text = 'Wavelength';
            app.WavelengthEditField = uieditfield(app.SettingsPanel, 'text');
            app.WavelengthEditField.Value = '980';
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Enable = 'off';
            app.resetButton.Text = 'reset';
            app.TestCircuitPanel = uipanel(app.UIFigure);
            app.writetofpgaButton = uibutton(app.TestCircuitPanel, 'push');
            app.writetofpgaButton.ButtonPushedFcn = createCallbackFcn(app, @writetofpgaButtonPushed, true);
            app.writetofpgaButton.Text = 'write to fpga';
            app.LDDN_CURRENTEditFieldLabel = uilabel(app.TestCircuitPanel);
            app.LDDN_CURRENTEditFieldLabel.Text = 'LDDN_CURRENT';
            app.LDDN_CURRENTEditField = uieditfield(app.TestCircuitPanel, 'text');
            app.Button = uibutton(app.TestCircuitPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'gu_save.png';
            app.Button.Text = '';
            app.dBmLabel = uilabel(app.TestCircuitPanel);
            app.dBmLabel.Text = 'dBm';
            app.IfthetestpassedpresssavebuttonLabel = uilabel(app.TestCircuitPanel);
            app.IfthetestpassedpresssavebuttonLabel.Text = 'If the test passed, press save button';
            app.Tip1Image_2 = uiimage(app.TestCircuitPanel);
            app.Tip1Image_2.Tooltip = {'We recommend to check values 1022,1023,1024.'};
            app.Tip1Image_2.ImageSource = 'gu_help.png';
            app.savevalueButton = uibutton(app.TestCircuitPanel, 'push');
            app.savevalueButton.Text = 'save value';
            app.OutputpowerEditFieldLabel = uilabel(app.TestCircuitPanel);
            app.OutputpowerEditFieldLabel.Text = 'Output power';
            app.OutputpowerEditField = uieditfield(app.TestCircuitPanel, 'numeric');
            app.AssembleopticalcircuitLabel = uilabel(app.TestCircuitPanel);
            app.AssembleopticalcircuitLabel.Text = 'Assemble optical circuit at picture, fill in fields';
            app.Image = uiimage(app.TestCircuitPanel);
            app.Image.ImageSource = 'FPGA_LaserDiode.png';
            app.Button_2 = uibutton(app.TestCircuitPanel, 'push');
            app.Button_2.Icon = 'gu_zoomin.png';
            app.Button_2.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1. Добавьте таблицу со всеми компонентами (дополняется строкой после проверки) и таблицу-шаблон для проверки лазерных диодов';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Лазерные диоды';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.LoadDriverEEPROMLabel = uilabel(app.UIFigure);
            app.LoadDriverEEPROMLabel.Text = '2. Если значение ниже равно нулю, найдите в таблице калибровки (Load Driver для этой платы) нужное значение и впишите его в EEPROM. Только после этого продолжайте проверку.';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '3. Введите значение тока, которое хотите выставить на плате и нажмите кнопку.';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '4. Измерьте мощность лазерного диода и впишите ниже';
            app.saveLabel = uilabel(app.UIFigure);
            app.saveLabel.Text = '5. Сохраните данные проверок - нажмите save';
            app.CheckOffsetPanel = uipanel(app.UIFigure);
            app.checkButton = uibutton(app.CheckOffsetPanel, 'push');
            app.checkButton.ButtonPushedFcn = createCallbackFcn(app, @checkButtonPushed, true);
            app.checkButton.Text = 'check';
            app.Tip1Image = uiimage(app.CheckOffsetPanel);
            app.Tip1Image.Tooltip = {'If this value 0 you would NOT be allowed to continue. You can change it in Device FPGA window (don''t forget to find right value and write it in EEPROM).'};
            app.Tip1Image.ImageSource = 'gu_help.png';
            app.LDDN_OFFSET_HIGH_RANGEEditFieldLabel = uilabel(app.CheckOffsetPanel);
            app.LDDN_OFFSET_HIGH_RANGEEditFieldLabel.Text = 'LDDN_OFFSET_HIGH_RANGE';
            app.LDDN_OFFSET_HIGH_RANGEEditField = uieditfield(app.CheckOffsetPanel, 'text');
            app.LDDN_OFFSET_HIGH_RANGEEditField.Editable = 'off';
            app.PoutputEditFieldLabel = uilabel(app.UIFigure);
            app.PoutputEditFieldLabel.Text = 'P output';
            app.PoutputEditField = uieditfield(app.UIFigure, 'text');
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTestFPGA_LaserDiode(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end