classdef appTestFPGA_LoadDriver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AssembleopticalcircuitLabel_2  matlab.ui.control.Label
        Voltagecurrent1023EditField    matlab.ui.control.EditField
        Voltagecurrent1023EditFieldLabel  matlab.ui.control.Label
        saveLabel                      matlab.ui.control.Label
        runnextLabel                   matlab.ui.control.Label
        Voltagecurrent1023Label        matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        designationLabel               matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        resetButton                    matlab.ui.control.Button
        PanelSettings                  matlab.ui.container.Panel
        SerialnumberEditField          matlab.ui.control.EditField
        SerialnumberEditFieldLabel     matlab.ui.control.Label
        Tip1Image_2                    matlab.ui.control.Image
        CommentEditField               matlab.ui.control.EditField
        CommentEditFieldLabel          matlab.ui.control.Label
        BoardnumberEditField           matlab.ui.control.EditField
        BoardnumberEditFieldLabel      matlab.ui.control.Label
        DesignationEditField           matlab.ui.control.EditField
        DesignationEditFieldLabel      matlab.ui.control.Label
        InspectornameEditField         matlab.ui.control.EditField
        InspectornameEditFieldLabel    matlab.ui.control.Label
        ApplyButton                    matlab.ui.control.Button
        downloadButton_2               matlab.ui.control.Button
        SelectsampletableforloaddriverTextArea  matlab.ui.control.TextArea
        Label_4                        matlab.ui.control.Label
        SelecttablewithallcomponentsTextArea  matlab.ui.control.TextArea
        SelecttablewithallcomponentsTextAreaLabel  matlab.ui.control.Label
        downloadButton_1               matlab.ui.control.Button
        TestFPGAloaddriverLabel        matlab.ui.control.Label
        PanelFunction                  matlab.ui.container.Panel
        Tip1Image                      matlab.ui.control.Image
        Voltagecurrent1023EditField_2  matlab.ui.control.NumericEditField
        Voltagecurrent1023EditField_2Label  matlab.ui.control.Label
        mVVLabel_2                     matlab.ui.control.Label
        IfthetestpassedpresssavebuttonLabel  matlab.ui.control.Label
        Button_3                       matlab.ui.control.Button
        Image_2                        matlab.ui.control.Image
        AssembleopticalcircuitLabel    matlab.ui.control.Label
        mVVLabel                       matlab.ui.control.Label
        runtestButton                  matlab.ui.control.Button
        nextButton                     matlab.ui.control.Button
        VoltageEditField               matlab.ui.control.NumericEditField
        VoltageEditFieldLabel          matlab.ui.control.Label
        LDDcurrentEditField            matlab.ui.control.EditField
        LDDcurrentEditFieldLabel       matlab.ui.control.Label
        Button_4                       matlab.ui.control.Button
        LDDN_CURRENTEditField          matlab.ui.control.EditField
        LDDN_CURRENTEditFieldLabel     matlab.ui.control.Label
        writetofpgaButton              matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        LDDN_OFFSETEditField           matlab.ui.control.EditField
        LDDN_OFFSETEditFieldLabel      matlab.ui.control.Label
    end


    properties (Access = public)
        callingApp
        folder
        time

        dac
        voltage

        fpga
        tableComponent
        tableLoadDriver

        LDnumber %string
        DACnumber %num
        %loadDriver
    end

    methods (Access = private)
        function dac = readTableForDAC(app)
            numRows = readtable(app.tableLoadDriver,'Sheet','Sample',...
                'ReadVariableNames',false,'ReadRowNames',true);
            dac = numRows{'Значение на ЦАП',:};
        end
        function app = writeDACvalue(app)
            number = str2double(app.LDnumber);
            valueNum = app.dac(app.DACnumber);
            app.fpga = setValueToLddCurrent(app.fpga,number,valueNum);
            app.LDDcurrentEditField.Value = app.fpga.lastRead;
            output = ['Номер измерения ',...
                num2str(app.DACnumber)];
            addStrOutput(app.callingApp,output);
            output = ['Значение ЦАП ',...
                num2str(app.dac(app.DACnumber))];
            addStrOutput(app.callingApp,output);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.callingApp = MainApp;
            app.folder = MainApp.folder;
            app.fpga = MainApp.calledAppFPGA.fpga;

            app.tableComponent = 'tableComponent.xlsx';
            app.tableLoadDriver = 'tableLoadDriver.xlsx';
        end

        % Button pushed function: downloadButton_1
        function downloadButton_1Pushed(app, event)
            [file,path] = uigetfile('*.xlsx','Select tableComponent file','tableComponent.xlsx');
            if isequal(file,0)
                output = "не выбрана";
            else
                output = [path file];
            end
            figure(app.UIFigure);
            app.tableComponent = output;
            app.SelecttablewithallcomponentsTextArea.Value = output;
        end

        % Button pushed function: downloadButton_2
        function downloadButton_2Pushed(app, event)
            [file,path] = uigetfile('*.xlsx','Select tableLoadDriver file','tableLoadDriver.xlsx');
            if isequal(file,0)
                output = "не выбрана";
            else
                output = [path file];
            end
            figure(app.UIFigure);
            app.tableLoadDriver = output;
            app.SelectsampletableforloaddriverTextArea.Value = output;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.LDnumber = app.DesignationEditField.Value;
            app.dac = readTableForDAC(app);

            fpgaName = ['LDD' app.LDnumber '_OFFSET_HIGH_RANGE'];
            wvalue = '0';
            app.fpga = writeDataToFPGA(app.fpga,fpgaName,wvalue);
            app.LDDN_OFFSETEditField.Value = app.fpga.lastRead;
            output = ['Значение ' wvalue ' записано в ' fpgaName];
            addStrOutput(app.callingApp,output);

            app.PanelSettings.Enable = 'off';
            app.PanelFunction.Enable = 'on';
        end

        % Button pushed function: writetofpgaButton
        function writetofpgaButtonPushed(app, event)
            number = str2double(app.LDnumber);
            wvalue = num2str(2^(number-1));
            app.fpga = writeDataToFPGA(app.fpga,'LDD_EN',wvalue);
            
            output = 'Диоды включены';
            addStrOutput(app.callingApp,output);
            enableWarningLaser(app.callingApp);

            fpgaName = ['LDD' app.LDnumber '_CURRENT'];
            wvalue = app.LDDN_CURRENTEditField.Value;
            app.fpga = writeDataToFPGA(app.fpga,fpgaName,wvalue);
            
            output = ['На диод N=' app.LDnumber ' подается ток ' wvalue];
            addStrOutput(app.callingApp,output);
        end

        % Button pushed function: runtestButton
        function runtestButtonPushed(app, event)
            app.DACnumber = 1;
            app = writeDACvalue(app);
            app.nextButton.Enable = 'on';
        end

        % Button pushed function: nextButton
        function nextButtonPushed(app, event)
            app.voltage(app.DACnumber) = app.VoltageEditField.Value;
            output = ['Значение напряжения ',...
                num2strForPrint(app.voltage(app.DACnumber))];
            addStrOutput(app.callingApp,output);
            if app.DACnumber < length(app.dac)
                app.DACnumber = app.DACnumber + 1;
                app = writeDACvalue(app);
            elseif app.DACnumber == length(app.dac)
                app.nextButton.Enable = 'off';
                addStrOutput(app.callingApp,'Все измерения проведены, можно сохранять');
            end
        end

        % Value changed function: LDDN_OFFSETEditField
        function LDDN_OFFSETEditFieldValueChanged(app, event)
            wvalue = app.LDDN_OFFSETEditField.Value;
            fpgaName = ['LDD' app.LDnumber '_OFFSET_HIGH_RANGE'];
            app.fpga = writeDataToFPGA(app.fpga,fpgaName,wvalue);
            app.LDDN_OFFSETEditField.Value = app.fpga.lastRead;
            output = ['Значение ' wvalue ' записано в ' fpgaName];
            addStrOutput(app.callingApp,output);
            
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            %app.callingApp.
            %delete(app);

        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.time = now;
            loadDriver = LoadDriver(app);

            fpgaName = ['LDD' app.LDnumber '_CURRENT'];
            wvalue = '0';
            app.fpga = writeDataToFPGA(app.fpga,fpgaName,wvalue);

            app.fpga = writeDataToFPGA(app.fpga,'LDD_EN','0');

            output = 'Диоды выключены';
            addStrOutput(app.callingApp,output);
            disableWarningLaser(app.callingApp);
            
            saveTable(loadDriver);
            loadDriverName = createFilename('Ld',app.time);
            save([app.folder loadDriverName '.mat'],'loadDriver');
            addStrOutput(app.callingApp,'Файлы сохранены');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PanelFunction = uipanel(app.UIFigure);
            app.PanelFunction.Enable = 'off';
            app.LDDN_OFFSETEditFieldLabel = uilabel(app.PanelFunction);
            app.LDDN_OFFSETEditFieldLabel.Tooltip = {'LDDN_OFFSET_HIGH_RANGE'};
            app.LDDN_OFFSETEditFieldLabel.Text = 'LDDN_OFFSET...';
            app.LDDN_OFFSETEditField = uieditfield(app.PanelFunction, 'text');
            app.LDDN_OFFSETEditField.ValueChangedFcn = createCallbackFcn(app, @LDDN_OFFSETEditFieldValueChanged, true);
            app.LDDN_OFFSETEditField.Tooltip = {'LDDN_OFFSET_HIGH_RANGE'; ''};
            app.Button = uibutton(app.PanelFunction, 'push');
            app.Button.Enable = 'off';
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.PanelFunction, 'push');
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '-';
            app.writetofpgaButton = uibutton(app.PanelFunction, 'push');
            app.writetofpgaButton.ButtonPushedFcn = createCallbackFcn(app, @writetofpgaButtonPushed, true);
            app.writetofpgaButton.Text = 'write to fpga';
            app.LDDN_CURRENTEditFieldLabel = uilabel(app.PanelFunction);
            app.LDDN_CURRENTEditFieldLabel.Text = 'LDDN_CURRENT';
            app.LDDN_CURRENTEditField = uieditfield(app.PanelFunction, 'text');
            app.LDDN_CURRENTEditField.Value = '1023';
            app.Button_4 = uibutton(app.PanelFunction, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'gu_save.png';
            app.Button_4.Text = '';
            app.LDDcurrentEditFieldLabel = uilabel(app.PanelFunction);
            app.LDDcurrentEditFieldLabel.Text = 'LDD current';
            app.LDDcurrentEditField = uieditfield(app.PanelFunction, 'text');
            app.LDDcurrentEditField.Editable = 'off';
            app.VoltageEditFieldLabel = uilabel(app.PanelFunction);
            app.VoltageEditFieldLabel.Text = 'Voltage';
            app.VoltageEditField = uieditfield(app.PanelFunction, 'numeric');
            app.nextButton = uibutton(app.PanelFunction, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.nextButton.Text = 'next';
            app.runtestButton = uibutton(app.PanelFunction, 'push');
            app.runtestButton.ButtonPushedFcn = createCallbackFcn(app, @runtestButtonPushed, true);
            app.runtestButton.Text = 'run test';
            app.mVVLabel = uilabel(app.PanelFunction);
            app.mVVLabel.Text = 'mV(V)';
            app.AssembleopticalcircuitLabel = uilabel(app.PanelFunction);
            app.AssembleopticalcircuitLabel.Text = 'Assemble circuit, fill in fields';
            app.Image_2 = uiimage(app.PanelFunction);
            app.Image_2.ImageSource = 'FPGA_LoadDriver.png';
            app.Button_3 = uibutton(app.PanelFunction, 'push');
            app.Button_3.Icon = 'gu_zoomin.png';
            app.Button_3.Text = '';
            app.IfthetestpassedpresssavebuttonLabel = uilabel(app.PanelFunction);
            app.IfthetestpassedpresssavebuttonLabel.Text = 'If the test passed, press save button';
            app.mVVLabel_2 = uilabel(app.PanelFunction);
            app.mVVLabel_2.Text = 'mV(V)';
            app.Voltagecurrent1023EditField_2Label = uilabel(app.PanelFunction);
            app.Voltagecurrent1023EditField_2Label.Text = 'Voltage (current 1023)';
            app.Voltagecurrent1023EditField_2 = uieditfield(app.PanelFunction, 'numeric');
            app.Tip1Image = uiimage(app.PanelFunction);
            app.Tip1Image.Tooltip = {''; 'Set LDD current 1024. Set different LDD offset until real Voltage become greater than Voltage (current 1023). Run test.'};
            app.Tip1Image.ImageSource = 'gu_help.png';
            app.TestFPGAloaddriverLabel = uilabel(app.UIFigure);
            app.TestFPGAloaddriverLabel.Text = 'Test FPGA load driver';
            app.PanelSettings = uipanel(app.UIFigure);
            app.downloadButton_1 = uibutton(app.PanelSettings, 'push');
            app.downloadButton_1.ButtonPushedFcn = createCallbackFcn(app, @downloadButton_1Pushed, true);
            app.downloadButton_1.Icon = 'gu_microsoft-excel.png';
            app.downloadButton_1.Text = '';
            app.SelecttablewithallcomponentsTextAreaLabel = uilabel(app.PanelSettings);
            app.SelecttablewithallcomponentsTextAreaLabel.Text = 'Select table with all components';
            app.SelecttablewithallcomponentsTextArea = uitextarea(app.PanelSettings);
            app.SelecttablewithallcomponentsTextArea.Value = {'tableComponent.xlsx'};
            app.Label_4 = uilabel(app.PanelSettings);
            app.Label_4.Text = 'Select sample table for load driver';
            app.SelectsampletableforloaddriverTextArea = uitextarea(app.PanelSettings);
            app.SelectsampletableforloaddriverTextArea.Value = {'tableLoadDriver.xlsx'};
            app.downloadButton_2 = uibutton(app.PanelSettings, 'push');
            app.downloadButton_2.ButtonPushedFcn = createCallbackFcn(app, @downloadButton_2Pushed, true);
            app.downloadButton_2.Icon = 'gu_microsoft-excel.png';
            app.downloadButton_2.Text = '';
            app.ApplyButton = uibutton(app.PanelSettings, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.InspectornameEditFieldLabel = uilabel(app.PanelSettings);
            app.InspectornameEditFieldLabel.Text = 'Inspector name';
            app.InspectornameEditField = uieditfield(app.PanelSettings, 'text');
            app.InspectornameEditField.Value = 'Фамилия И.О.';
            app.DesignationEditFieldLabel = uilabel(app.PanelSettings);
            app.DesignationEditFieldLabel.Text = 'Designation';
            app.DesignationEditField = uieditfield(app.PanelSettings, 'text');
            app.DesignationEditField.Value = 'Ld1';
            app.BoardnumberEditFieldLabel = uilabel(app.PanelSettings);
            app.BoardnumberEditFieldLabel.Text = 'Board number';
            app.BoardnumberEditField = uieditfield(app.PanelSettings, 'text');
            app.BoardnumberEditField.Value = '01';
            app.CommentEditFieldLabel = uilabel(app.PanelSettings);
            app.CommentEditFieldLabel.Text = 'Comment';
            app.CommentEditField = uieditfield(app.PanelSettings, 'text');
            app.CommentEditField.Value = 'до заливки';
            app.Tip1Image_2 = uiimage(app.PanelSettings);
            app.Tip1Image_2.Tooltip = {'match diode designation. in the end must be LDD number'};
            app.Tip1Image_2.ImageSource = 'gu_help.png';
            app.SerialnumberEditFieldLabel = uilabel(app.PanelSettings);
            app.SerialnumberEditFieldLabel.Text = 'Serial number';
            app.SerialnumberEditField = uieditfield(app.PanelSettings, 'text');
            app.SerialnumberEditField.Value = '-';
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Enable = 'off';
            app.resetButton.Text = 'reset';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1. Добавьте таблицу со всеми компонентами (дополняется строкой после проверки) и таблицу-шаблон для проверки драйвера (имитатора нагрузки)';
            app.designationLabel = uilabel(app.UIFigure);
            app.designationLabel.Text = '2. Установите драйвер нагрузки согласно рисунку. Подайте ток 1023 на указанный "диод" (designation), пользуясь полем и кнопкой ниже. Введите напряжение для этого значения тока';
            app.Image = uiimage(app.UIFigure);
            app.Voltagecurrent1023Label = uilabel(app.UIFigure);
            app.Voltagecurrent1023Label.Text = '3. Подайте ток 1024. Изменяйте оффсет пока не получите значение напряжения чуть больше, чем Voltage (current 1023)';
            app.runnextLabel = uilabel(app.UIFigure);
            app.runnextLabel.Text = '4. Когда значение будет подобрано, нажмите run. Ток на диоде будет меняться (из таблицы) и его значение вы можете видеть ниже, вводите напряжение для каждого значения и нажимайте  next';
            app.saveLabel = uilabel(app.UIFigure);
            app.saveLabel.Text = '5. Сохраните данные проверок - нажмите save';
            app.Voltagecurrent1023EditFieldLabel = uilabel(app.UIFigure);
            app.Voltagecurrent1023EditFieldLabel.Text = 'Voltage (current 1023)';
            app.Voltagecurrent1023EditField = uieditfield(app.UIFigure, 'text');
            app.AssembleopticalcircuitLabel_2 = uilabel(app.UIFigure);
            app.AssembleopticalcircuitLabel_2.Text = 'Set LDD current 1024. Set different LDD offset until real Voltage become greater than Voltage (current 1023). Run test.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTestFPGA_LoadDriver(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end