classdef appTestFPGA_Parameter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TemperatureldEditField         matlab.ui.control.NumericEditField
        TemperatureldEditFieldLabel    matlab.ui.control.Label
        TemperaturefpgaEditField       matlab.ui.control.NumericEditField
        TemperaturefpgaEditFieldLabel  matlab.ui.control.Label
        CLabel_3                       matlab.ui.control.Label
        CLabel_2                       matlab.ui.control.Label
        readButton                     matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        resetButton                    matlab.ui.control.Button
        PanelSettings                  matlab.ui.container.Panel
        SelectsampletableforphotodiodeTextArea  matlab.ui.control.TextArea
        Label_3                        matlab.ui.control.Label
        SelecttablewithallcomponentsTextArea  matlab.ui.control.TextArea
        SelecttablewithallcomponentsTextAreaLabel  matlab.ui.control.Label
        downloadButton_2               matlab.ui.control.Button
        downloadButton_1               matlab.ui.control.Button
        ApplyButton                    matlab.ui.control.Button
        CommentEditField               matlab.ui.control.EditField
        CommentEditFieldLabel          matlab.ui.control.Label
        BoardnumberEditField           matlab.ui.control.EditField
        BoardnumberEditFieldLabel      matlab.ui.control.Label
        InspectornameEditField         matlab.ui.control.EditField
        InspectornameEditFieldLabel    matlab.ui.control.Label
        TestFPGAparametersLabel        matlab.ui.control.Label
        PanelFunction                  matlab.ui.container.Panel
        Label_4                        matlab.ui.control.Label
        IfthetestpassedpresssavebuttonLabel  matlab.ui.control.Label
        CLabel                         matlab.ui.control.Label
        TemperatureoutsideEditField    matlab.ui.control.NumericEditField
        TemperatureoutsideLabel        matlab.ui.control.Label
        ALabel_2                       matlab.ui.control.Label
        CurrentmaxEditField            matlab.ui.control.NumericEditField
        CurrentmaxEditFieldLabel       matlab.ui.control.Label
        CurrentEditField               matlab.ui.control.NumericEditField
        CurrentEditFieldLabel_2        matlab.ui.control.Label
        ALabel                         matlab.ui.control.Label
        VLabel                         matlab.ui.control.Label
        VoltageEditField               matlab.ui.control.NumericEditField
        VoltageLabel                   matlab.ui.control.Label
        doneLabel                      matlab.ui.control.Label
        testboardparamButton           matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        EthernetDropDown               matlab.ui.control.DropDown
        EthernetDropDownLabel          matlab.ui.control.Label
    end

    
    properties (Access = public)
        callingApp
        folder
        time
        fpga
        tableComponent
        tableOtherParameter
        paramFromBoard
        criteria
    end
    
    methods (Access = public)
        
        function results = requestOtherParameter(app)
            criteriaCell = readcell(app.tableOtherParameter,...
                'Sheet','Criteria',...
                'NumHeaderLines',1);
            nameCell = criteriaCell(:,2);
            minCell = criteriaCell(:,3);
            maxCell = criteriaCell(:,4);
            len = size(criteriaCell,1);
            for i = 1:len
                name = nameCell{i};
                min = minCell{i};
                max = maxCell{i};
                app.fpga = readDataFromFPGA(app.fpga,name);
                valueHex = app.fpga.lastRead;
                results{i,1} = valueHex;
                valueV = 2.5/(2^12) * hex2dec(valueHex);
                results{i,2} = valueV;
                if min <= valueV && valueV <= max
                    results{i,3} = 1;
                else
                    results{i,3} = 0;
                end
                output = [name ' = ' num2str(valueV) ' V, ',...
                    num2str(results{i,3})];
                addStrOutput(app.callingApp,output);
            end
            criteriaCellArr = results(:,3);
            criteriaArr = cell2mat(criteriaCellArr);
            if ismember(0,criteriaArr)
                results = [{'нет' '' ''}; results];
            else 
                results = [{'да' '' ''}; results];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.callingApp = MainApp;
            app.folder = MainApp.folder;
            app.fpga = MainApp.calledAppFPGA.fpga;

            app.tableComponent = 'tableComponent.xlsx';
            app.tableOtherParameter = 'tableOtherParameter.xlsx';
        end

        % Button pushed function: downloadButton_1
        function downloadButton_1Pushed(app, event)
            [file,path] = uigetfile('*.xlsx','Select tableComponent file','tableComponent.xlsx');
            if isequal(file,0)
                output = "не выбрана";
            else
                output = [path file];
            end
            figure(app.UIFigure);
            app.tableComponent = output;
            app.SelecttablewithallcomponentsTextArea.Value = output;
        end

        % Button pushed function: downloadButton_2
        function downloadButton_2Pushed(app, event)
            [file,path] = uigetfile('*.xlsx','Select tableOtherParameter file','tableOtherParameter.xlsx');
            if isequal(file,0)
                output = "не выбрана";
            else
                output = [path file];
            end
            figure(app.UIFigure);
            app.tableOtherParameter = output;
            app.SelectsampletableforphotodiodeTextArea.Value = output;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.PanelSettings.Enable = 'off';
            app.time = now;
            app.PanelFunction.Enable = 'on';
        end

        % Button pushed function: readButton
        function readButtonPushed(app, event)
            app.fpga = readDataFromFPGA(app.fpga,'TEMP_CS');
            TEMP_CS = str2double(app.fpga.lastRead)/10;
            app.TemperatureoutsideEditField.Value = TEMP_CS;
            app.fpga = readDataFromFPGA(app.fpga,'TEMPERATURE_FPGA');
            TEMPERATURE_FPGA = str2double(app.fpga.lastRead)/100;
            app.TemperaturefpgaEditField.Value = TEMPERATURE_FPGA;
            app.fpga = readDataFromFPGA(app.fpga,'TEMPERATURE_LD');
            TEMPERATURE_LD = str2double(app.fpga.lastRead)/100;
            app.TemperatureldEditField.Value = TEMPERATURE_LD;
        end

        % Button pushed function: testboardparamButton
        function testboardparamButtonPushed(app, event)
            app.paramFromBoard = requestOtherParameter(app);
            %app.criteria = app.paramFromBoard(2:end,2);
            app.paramFromBoard = app.paramFromBoard(:,1:3);
            
            app.doneLabel.Visible = 'on';
            app.Button.Enable = 'on';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %app.PanelFunction.Enable = 'off';
            
            otherParameter = OtherParameter(app);
            saveTable(otherParameter);
            otherParametersName = createFilename('OP',app.time);
            %criteria = app.criteria;
            save([app.folder otherParametersName '.mat'],'otherParameter');%,'criteria'
            addStrOutput(app.callingApp,'Файлы сохранены');
            
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            %app.callingApp.
            %delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PanelFunction = uipanel(app.UIFigure);
            app.PanelFunction.Enable = 'off';
            app.EthernetDropDownLabel = uilabel(app.PanelFunction);
            app.EthernetDropDownLabel.Text = 'Ethernet';
            app.EthernetDropDown = uidropdown(app.PanelFunction);
            app.EthernetDropDown.Items = {'не проверен', 'работает', 'не работает'};
            app.EthernetDropDown.Value = 'не проверен';
            app.Button = uibutton(app.PanelFunction, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'gu_save.png';
            app.Button.Text = '';
            app.testboardparamButton = uibutton(app.PanelFunction, 'push');
            app.testboardparamButton.ButtonPushedFcn = createCallbackFcn(app, @testboardparamButtonPushed, true);
            app.testboardparamButton.Text = 'test board param';
            app.doneLabel = uilabel(app.PanelFunction);
            app.doneLabel.Visible = 'off';
            app.doneLabel.Text = 'done';
            app.VoltageLabel = uilabel(app.PanelFunction);
            app.VoltageLabel.Text = 'Voltage';
            app.VoltageEditField = uieditfield(app.PanelFunction, 'numeric');
            app.VLabel = uilabel(app.PanelFunction);
            app.VLabel.Text = 'V';
            app.ALabel = uilabel(app.PanelFunction);
            app.ALabel.Text = 'A';
            app.CurrentEditFieldLabel_2 = uilabel(app.PanelFunction);
            app.CurrentEditFieldLabel_2.Text = 'Current';
            app.CurrentEditField = uieditfield(app.PanelFunction, 'numeric');
            app.CurrentmaxEditFieldLabel = uilabel(app.PanelFunction);
            app.CurrentmaxEditFieldLabel.Text = 'Current max';
            app.CurrentmaxEditField = uieditfield(app.PanelFunction, 'numeric');
            app.ALabel_2 = uilabel(app.PanelFunction);
            app.ALabel_2.Text = 'A';
            app.TemperatureoutsideLabel = uilabel(app.PanelFunction);
            app.TemperatureoutsideLabel.Text = 'Temperature outside';
            app.TemperatureoutsideEditField = uieditfield(app.PanelFunction, 'numeric');
            app.TemperatureoutsideEditField.Editable = 'off';
            app.CLabel = uilabel(app.PanelFunction);
            app.CLabel.Text = '°C';
            app.IfthetestpassedpresssavebuttonLabel = uilabel(app.PanelFunction);
            app.IfthetestpassedpresssavebuttonLabel.Text = 'If the test passed, press save button';
            app.Label_4 = uilabel(app.PanelFunction);
            app.Label_4.Text = 'Fill in fields below manually and press button to test parameters from table';
            app.TestFPGAparametersLabel = uilabel(app.UIFigure);
            app.TestFPGAparametersLabel.Text = 'Test FPGA parameters';
            app.PanelSettings = uipanel(app.UIFigure);
            app.InspectornameEditFieldLabel = uilabel(app.PanelSettings);
            app.InspectornameEditFieldLabel.Text = 'Inspector name';
            app.InspectornameEditField = uieditfield(app.PanelSettings, 'text');
            app.InspectornameEditField.Value = 'Фамилия И.О.';
            app.BoardnumberEditFieldLabel = uilabel(app.PanelSettings);
            app.BoardnumberEditFieldLabel.Text = 'Board number';
            app.BoardnumberEditField = uieditfield(app.PanelSettings, 'text');
            app.BoardnumberEditField.Value = '02';
            app.CommentEditFieldLabel = uilabel(app.PanelSettings);
            app.CommentEditFieldLabel.Text = 'Comment';
            app.CommentEditField = uieditfield(app.PanelSettings, 'text');
            app.ApplyButton = uibutton(app.PanelSettings, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.downloadButton_1 = uibutton(app.PanelSettings, 'push');
            app.downloadButton_1.ButtonPushedFcn = createCallbackFcn(app, @downloadButton_1Pushed, true);
            app.downloadButton_1.Icon = 'gu_microsoft-excel.png';
            app.downloadButton_1.Text = '';
            app.downloadButton_2 = uibutton(app.PanelSettings, 'push');
            app.downloadButton_2.ButtonPushedFcn = createCallbackFcn(app, @downloadButton_2Pushed, true);
            app.downloadButton_2.Icon = 'gu_microsoft-excel.png';
            app.downloadButton_2.Text = '';
            app.SelecttablewithallcomponentsTextAreaLabel = uilabel(app.PanelSettings);
            app.SelecttablewithallcomponentsTextAreaLabel.Text = 'Select table with all components';
            app.SelecttablewithallcomponentsTextArea = uitextarea(app.PanelSettings);
            app.SelecttablewithallcomponentsTextArea.Value = {'tableComponent.xlsx'};
            app.Label_3 = uilabel(app.PanelSettings);
            app.Label_3.Text = 'Select sample table for photodiode';
            app.SelectsampletableforphotodiodeTextArea = uitextarea(app.PanelSettings);
            app.SelectsampletableforphotodiodeTextArea.Value = {'tableOtherParameter.xlsx'};
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Enable = 'off';
            app.resetButton.Text = 'reset';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Добавьте таблицу со всеми компонентами (дополняется строкой после проверки) и таблицу-шаблон для проверки';
            app.readButton = uibutton(app.UIFigure, 'push');
            app.readButton.ButtonPushedFcn = createCallbackFcn(app, @readButtonPushed, true);
            app.readButton.Text = 'read';
            app.CLabel_2 = uilabel(app.UIFigure);
            app.CLabel_2.Text = '°C';
            app.CLabel_3 = uilabel(app.UIFigure);
            app.CLabel_3.Text = '°C';
            app.TemperaturefpgaEditFieldLabel = uilabel(app.UIFigure);
            app.TemperaturefpgaEditFieldLabel.Text = 'Temperature fpga';
            app.TemperaturefpgaEditField = uieditfield(app.UIFigure, 'numeric');
            app.TemperatureldEditFieldLabel = uilabel(app.UIFigure);
            app.TemperatureldEditFieldLabel.Text = 'Temperature ld';
            app.TemperatureldEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTestFPGA_Parameter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end