classdef appTestFPGA_Transceiver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        saveLabel                      matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        IncreaseattenuationwithLabel   matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        Label_4                        matlab.ui.control.Label
        applyLabel                     matlab.ui.control.Label
        resetButton                    matlab.ui.control.Button
        PanelSettings                  matlab.ui.container.Panel
        SerialnumberEditField          matlab.ui.control.EditField
        SerialnumberEditFieldLabel     matlab.ui.control.Label
        DesignationEditField           matlab.ui.control.EditField
        DesignationEditFieldLabel      matlab.ui.control.Label
        SelectsampletablefortransceiverTextArea  matlab.ui.control.TextArea
        Label_3                        matlab.ui.control.Label
        SelecttablewithallcomponentsTextArea  matlab.ui.control.TextArea
        SelecttablewithallcomponentsTextAreaLabel  matlab.ui.control.Label
        CommentEditField               matlab.ui.control.EditField
        CommentEditFieldLabel          matlab.ui.control.Label
        OperatingwlEditField           matlab.ui.control.EditField
        OperatingwlEditFieldLabel      matlab.ui.control.Label
        TestmodeDropDown               matlab.ui.control.DropDown
        TestmodeDropDownLabel          matlab.ui.control.Label
        downloadButton_2               matlab.ui.control.Button
        downloadButton_1               matlab.ui.control.Button
        ApplySettingsButton            matlab.ui.control.Button
        BoardnumberEditField           matlab.ui.control.EditField
        BoardnumberEditFieldLabel      matlab.ui.control.Label
        InspectornameEditField         matlab.ui.control.EditField
        InspectornameEditFieldLabel    matlab.ui.control.Label
        TesttransceiverLabel           matlab.ui.control.Label
        PanelFunction                  matlab.ui.container.Panel
        Tip1Image_2                    matlab.ui.control.Image
        Tip1Image                      matlab.ui.control.Image
        AssembleopticalcircuitLabel_2  matlab.ui.control.Label
        IfthetestpassedpresssavebuttonLabel  matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        Image_2                        matlab.ui.control.Image
        AssembleopticalcircuitLabel    matlab.ui.control.Label
        statusBERLabel                 matlab.ui.control.Label
        startcheckinglinkButton        matlab.ui.control.StateButton
        TimefortestEditField           matlab.ui.control.NumericEditField
        TimefortestEditFieldLabel      matlab.ui.control.Label
        sLabel_2                       matlab.ui.control.Label
        testBERButton                  matlab.ui.control.Button
        Inputpowerlink01EditField      matlab.ui.control.NumericEditField
        Inputpowerlink01Label          matlab.ui.control.Label
        Inputpowerlink10EditField      matlab.ui.control.NumericEditField
        Inputpowerlink10Label          matlab.ui.control.Label
        PeriodEditField                matlab.ui.control.NumericEditField
        PeriodEditFieldLabel           matlab.ui.control.Label
        dBmLabel_4                     matlab.ui.control.Label
        dBmLabel_3                     matlab.ui.control.Label
        sLabel                         matlab.ui.control.Label
        checklinkonceButton            matlab.ui.control.Button
        LinkLabel                      matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        Button_2                       matlab.ui.control.Button
        OutputpowerEditField           matlab.ui.control.NumericEditField
        OutputpowerEditFieldLabel      matlab.ui.control.Label
        dBmLabel                       matlab.ui.control.Label
    end


    properties (Access = public)
        callingApp
        folder
        time
        fpga
        opm
        tableComponent
        tableTransceiver

        %transceiver
        link
        ber
        transNumber
    end

    methods (Access = public)
        function app = showLink(app)
            if app.link == 1
                app.Lamp.Color = [1.00,0.00,0.00];
                addStrOutput(app.callingApp,'LINK 1');
            elseif app.link == 0
                app.Lamp.Color = [1,1,1];
                addStrOutput(app.callingApp,'LINK 0');
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.callingApp = MainApp;
            app.folder = MainApp.folder;


            app.fpga = MainApp.calledAppFPGA.fpga;
            if ~isempty(MainApp.calledAppOPMrubin)
                app.opm = MainApp.calledAppOPMrubin.opmArr.opticalPowerMeters(1);
            end

            app.tableComponent = 'tableComponent.xlsx';
            app.tableTransceiver = 'tableTransceiver.xlsx';
        end

        % Button pushed function: downloadButton_1
        function downloadButton_1Pushed(app, event)
            [file,path] = uigetfile('*.xlsx','Select tableComponent file','tableComponent.xlsx');
            if isequal(file,0)
                output = "не выбрана";
            else
                output = [path file];
            end
            figure(app.UIFigure);
            app.tableComponent = output;
            app.SelecttablewithallcomponentsTextArea.Value = output;
        end

        % Button pushed function: downloadButton_2
        function downloadButton_2Pushed(app, event)
            [file,path] = uigetfile('*.xlsx','Select tableTransceiver file','tableTransceiver.xlsx');
            if isequal(file,0)
                output = "не выбрана";
            else
                output = [path file];
            end
            figure(app.UIFigure);
            app.tableTransceiver = output;
            app.SelectsampletablefortransceiverTextArea.Value = output;
        end

        % Button pushed function: ApplySettingsButton
        function ApplySettingsButtonPushed(app, event)
            try
                designation = app.DesignationEditField.Value;
                if isequal(designation,'A5')
                    app.transNumber = 1;
                elseif isequal(designation,'A6')
                    app.transNumber = 2;
                elseif isequal(designation,'A7')
                    app.transNumber = 3;
                elseif isequal(designation,'A8')
                    app.transNumber = 4;
                end
                addStrOutput(app.callingApp,['Номер трансивера определен как ',...
                    num2str(app.transNumber)]);
                app.fpga = enableBERT(app.fpga,app.transNumber);

                app.PanelFunction.Enable = 'on';
                %                 if isequal(app.transceiver.testMode,'manual')
                %                     app.OutputpowerEditField.Editable ='on';
                %                     app.ApplyOrReadRubinButton.Text = 'apply';
                %                 elseif isequal(app.transceiver.testMode,'auto')
                %                     app.OutputpowerEditField.Editable ='off';
                %                     app.ApplyOrReadRubinButton.Text = 'read power';
                %                 end
                app.PanelSettings.Enable = 'off';
            catch ME
                fig = uifigure;
                uialert(fig,ME.message,'Failed');
            end
        end

        % Button pushed function: checklinkonceButton
        function checklinkonceButtonPushed(app, event)
            app.link = checkForLink(app.fpga,app.transNumber);
            app = showLink(app);
        end

        % Value changed function: startcheckinglinkButton
        function startcheckinglinkButtonValueChanged(app, event)
            value = app.startcheckinglinkButton.Value;
            while value
                app.link = checkForLink(app.fpga,app.transNumber);
                app = showLink(app);
                pause(app.PeriodEditField.Value);
                app.startcheckinglinkButton.Text = 'checking link...';
            end
            if ~value
                app.startcheckinglinkButton.Text = 'start checking link';
            end
        end

        % Button pushed function: testBERButton
        function testBERButtonPushed(app, event)
            app.statusBERLabel.Visible = 'on';
            app.statusBERLabel.Text = 'process...';

            resultStruct = checkBERT(app.fpga,app.transNumber);

            app.ber = resultStruct.BERT_BER;
            addStrOutput(app.callingApp,['BER = ',...
                num2strForPrint(app.ber)]);
            app.link = resultStruct.BERT_LOCKED;

            app.statusBERLabel.Text = 'done';
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.time = now;
            transceiver = Transceiver(app);

            saveTable(transceiver);
            %transceiver = app.transceiver;
            transceiverName = createFilename('TR',app.time);
            save([app.folder transceiverName '.mat'],'transceiver');
            addStrOutput(app.callingApp,'Файлы сохранены');
            app.fpga = disableBERT(app.fpga,app.transNumber);
            app.Button_2.Enable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PanelFunction = uipanel(app.UIFigure);
            app.PanelFunction.Enable = 'off';
            app.dBmLabel = uilabel(app.PanelFunction);
            app.dBmLabel.Text = 'dBm';
            app.OutputpowerEditFieldLabel = uilabel(app.PanelFunction);
            app.OutputpowerEditFieldLabel.Text = 'Output power';
            app.OutputpowerEditField = uieditfield(app.PanelFunction, 'numeric');
            app.Button_2 = uibutton(app.PanelFunction, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'gu_save.png';
            app.Button_2.Text = '';
            app.Lamp = uilamp(app.PanelFunction);
            app.LinkLabel = uilabel(app.PanelFunction);
            app.LinkLabel.Text = 'Link';
            app.checklinkonceButton = uibutton(app.PanelFunction, 'push');
            app.checklinkonceButton.ButtonPushedFcn = createCallbackFcn(app, @checklinkonceButtonPushed, true);
            app.checklinkonceButton.Text = 'check link once';
            app.sLabel = uilabel(app.PanelFunction);
            app.sLabel.Text = 's';
            app.dBmLabel_3 = uilabel(app.PanelFunction);
            app.dBmLabel_3.Text = 'dBm';
            app.dBmLabel_4 = uilabel(app.PanelFunction);
            app.dBmLabel_4.Text = 'dBm';
            app.PeriodEditFieldLabel = uilabel(app.PanelFunction);
            app.PeriodEditFieldLabel.Text = 'Period';
            app.PeriodEditField = uieditfield(app.PanelFunction, 'numeric');
            app.PeriodEditField.Value = 1;
            app.Inputpowerlink10Label = uilabel(app.PanelFunction);
            app.Inputpowerlink10Label.Text = 'Input power (link 1->0)';
            app.Inputpowerlink10EditField = uieditfield(app.PanelFunction, 'numeric');
            app.Inputpowerlink01Label = uilabel(app.PanelFunction);
            app.Inputpowerlink01Label.Text = 'Input power (link 0->1)';
            app.Inputpowerlink01EditField = uieditfield(app.PanelFunction, 'numeric');
            app.testBERButton = uibutton(app.PanelFunction, 'push');
            app.testBERButton.ButtonPushedFcn = createCallbackFcn(app, @testBERButtonPushed, true);
            app.testBERButton.Visible = 'off';
            app.testBERButton.Text = 'test BER';
            app.sLabel_2 = uilabel(app.PanelFunction);
            app.sLabel_2.Visible = 'off';
            app.sLabel_2.Text = 's';
            app.TimefortestEditFieldLabel = uilabel(app.PanelFunction);
            app.TimefortestEditFieldLabel.Visible = 'off';
            app.TimefortestEditFieldLabel.Text = 'Time for test';
            app.TimefortestEditField = uieditfield(app.PanelFunction, 'numeric');
            app.TimefortestEditField.Visible = 'off';
            app.TimefortestEditField.Value = 60;
            app.startcheckinglinkButton = uibutton(app.PanelFunction, 'state');
            app.startcheckinglinkButton.ValueChangedFcn = createCallbackFcn(app, @startcheckinglinkButtonValueChanged, true);
            app.startcheckinglinkButton.Enable = 'off';
            app.startcheckinglinkButton.Text = 'start checking link';
            app.statusBERLabel = uilabel(app.PanelFunction);
            app.statusBERLabel.Visible = 'off';
            app.statusBERLabel.Text = 'done';
            app.AssembleopticalcircuitLabel = uilabel(app.PanelFunction);
            app.AssembleopticalcircuitLabel.Text = {'Assemble optical circuit at picture 1, fill in output power.'; 'Assemble optical circuit at picture 2'};
            app.Image_2 = uiimage(app.PanelFunction);
            app.Image_2.ImageSource = 'FPGA_Transceiver.png';
            app.Button = uibutton(app.PanelFunction, 'push');
            app.Button.Icon = 'gu_zoomin.png';
            app.Button.Text = '';
            app.IfthetestpassedpresssavebuttonLabel = uilabel(app.PanelFunction);
            app.IfthetestpassedpresssavebuttonLabel.Text = 'If the test passed, press save button';
            app.AssembleopticalcircuitLabel_2 = uilabel(app.PanelFunction);
            app.AssembleopticalcircuitLabel_2.Visible = 'off';
            app.AssembleopticalcircuitLabel_2.Text = 'Set total attenuation minus 36 dB, test BER';
            app.Tip1Image = uiimage(app.PanelFunction);
            app.Tip1Image.Tooltip = {'Increase attenuation and check link at every step.'};
            app.Tip1Image.ImageSource = 'gu_help.png';
            app.Tip1Image_2 = uiimage(app.PanelFunction);
            app.Tip1Image_2.Tooltip = {'Decrease attenuation and check link at every step.'};
            app.Tip1Image_2.ImageSource = 'gu_help.png';
            app.TesttransceiverLabel = uilabel(app.UIFigure);
            app.TesttransceiverLabel.Text = 'Test transceiver';
            app.PanelSettings = uipanel(app.UIFigure);
            app.InspectornameEditFieldLabel = uilabel(app.PanelSettings);
            app.InspectornameEditFieldLabel.Text = 'Inspector name';
            app.InspectornameEditField = uieditfield(app.PanelSettings, 'text');
            app.InspectornameEditField.Value = 'Фамилия И.О.';
            app.BoardnumberEditFieldLabel = uilabel(app.PanelSettings);
            app.BoardnumberEditFieldLabel.Text = 'Board number';
            app.BoardnumberEditField = uieditfield(app.PanelSettings, 'text');
            app.BoardnumberEditField.Value = '01';
            app.ApplySettingsButton = uibutton(app.PanelSettings, 'push');
            app.ApplySettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplySettingsButtonPushed, true);
            app.ApplySettingsButton.Text = 'Apply';
            app.downloadButton_1 = uibutton(app.PanelSettings, 'push');
            app.downloadButton_1.ButtonPushedFcn = createCallbackFcn(app, @downloadButton_1Pushed, true);
            app.downloadButton_1.Icon = 'gu_microsoft-excel.png';
            app.downloadButton_1.Text = '';
            app.downloadButton_2 = uibutton(app.PanelSettings, 'push');
            app.downloadButton_2.ButtonPushedFcn = createCallbackFcn(app, @downloadButton_2Pushed, true);
            app.downloadButton_2.Icon = 'gu_microsoft-excel.png';
            app.downloadButton_2.Text = '';
            app.TestmodeDropDownLabel = uilabel(app.PanelSettings);
            app.TestmodeDropDownLabel.Enable = 'off';
            app.TestmodeDropDownLabel.Visible = 'off';
            app.TestmodeDropDownLabel.Text = 'Test mode';
            app.TestmodeDropDown = uidropdown(app.PanelSettings);
            app.TestmodeDropDown.Items = {'manual', 'auto'};
            app.TestmodeDropDown.Enable = 'off';
            app.TestmodeDropDown.Visible = 'off';
            app.TestmodeDropDown.Value = 'manual';
            app.OperatingwlEditFieldLabel = uilabel(app.PanelSettings);
            app.OperatingwlEditFieldLabel.Text = 'Operating wl';
            app.OperatingwlEditField = uieditfield(app.PanelSettings, 'text');
            app.CommentEditFieldLabel = uilabel(app.PanelSettings);
            app.CommentEditFieldLabel.Text = 'Comment';
            app.CommentEditField = uieditfield(app.PanelSettings, 'text');
            app.CommentEditField.Value = 'до заливки';
            app.SelecttablewithallcomponentsTextAreaLabel = uilabel(app.PanelSettings);
            app.SelecttablewithallcomponentsTextAreaLabel.Text = 'Select table with all components';
            app.SelecttablewithallcomponentsTextArea = uitextarea(app.PanelSettings);
            app.SelecttablewithallcomponentsTextArea.Value = {'tableComponent.xlsx'};
            app.Label_3 = uilabel(app.PanelSettings);
            app.Label_3.Text = 'Select sample table for transceiver';
            app.SelectsampletablefortransceiverTextArea = uitextarea(app.PanelSettings);
            app.SelectsampletablefortransceiverTextArea.Value = {'tableTransceiver.xlsx'};
            app.DesignationEditFieldLabel = uilabel(app.PanelSettings);
            app.DesignationEditFieldLabel.Text = 'Designation';
            app.DesignationEditField = uieditfield(app.PanelSettings, 'text');
            app.SerialnumberEditFieldLabel = uilabel(app.PanelSettings);
            app.SerialnumberEditFieldLabel.Text = 'Serial number';
            app.SerialnumberEditField = uieditfield(app.PanelSettings, 'text');
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.Enable = 'off';
            app.resetButton.Text = 'reset';
            app.applyLabel = uilabel(app.UIFigure);
            app.applyLabel.Text = '1. Добавьте таблицу со всеми компонентами (дополняется строкой после проверки) и таблицу-шаблон для проверки трансивера. Заполните все поля ниже и нажмите apply';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '2. Соберите схему. Введите выходную мощность в поле ниже.';
            app.Image = uiimage(app.UIFigure);
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '3. Увеличивайте аттенюацию. Когда линк пропадет, впишите значение аттенюации ниже.';
            app.IncreaseattenuationwithLabel = uilabel(app.UIFigure);
            app.IncreaseattenuationwithLabel.Text = 'Increase attenuation with ';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '4. Уменьшайте аттенюацию. Когда линк появится, впишите значение аттенюации ниже.';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '5. Выставите суммарную аттенюацию -36 дБ и запустите проверку.';
            app.saveLabel = uilabel(app.UIFigure);
            app.saveLabel.Text = '6. Сохраните данные проверок - нажмите save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTestFPGA_Transceiver(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end